classdef APP_Endstueckeditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UITable                        matlab.ui.control.Table
        SpeichernundLadenvonEinstellungenPanel  matlab.ui.container.Panel
        FormladenButton                matlab.ui.control.Button
        FormlschenButton               matlab.ui.control.Button
        FormspeichernButton            matlab.ui.control.Button
        bestehendeEndstckformenDropDownLabel  matlab.ui.control.Label
        bestehendeEndstckformenDropDown  matlab.ui.control.DropDown
        NamederEndstckformEditFieldLabel  matlab.ui.control.Label
        NamederEndstckformEditField    matlab.ui.control.EditField
        KonstruktionselementehinzufgenndernoderlschenPanel  matlab.ui.container.Panel
        hinzufgenButton                matlab.ui.control.Button
        ElementhinzufgenDropDownLabel  matlab.ui.control.Label
        ElementhinzufgenDropDown       matlab.ui.control.DropDown
        DurchmesserEditFieldLabel      matlab.ui.control.Label
        DurchmesserEditField           matlab.ui.control.EditField
        WinkelEditFieldLabel           matlab.ui.control.Label
        WinkelEditField                matlab.ui.control.EditField
        grad                           matlab.ui.control.Label
        mm                             matlab.ui.control.Label
        ndernButton                    matlab.ui.control.Button
        ElementNummerbearbeitenEditFieldLabel  matlab.ui.control.Label
        ElementNummerbearbeitenEditField  matlab.ui.control.EditField
        lschenButton_2                 matlab.ui.control.Button
        AllgemeineEinstellungenPanel   matlab.ui.container.Panel
        AnzahlderGenerationenEditFieldLabel_4  matlab.ui.control.Label
        Wandstaerke_mm                 matlab.ui.control.Label
        EndstueckSchliessen_CB         matlab.ui.control.CheckBox
        Wandstaerke_CB                 matlab.ui.control.CheckBox
        Wandstaerke_EF                 matlab.ui.control.NumericEditField
        Wandstaerke_Hauptmodell_LB     matlab.ui.control.Label
        WS_HM_Label                    matlab.ui.control.Label
        PlotaktualisierenButton        matlab.ui.control.Button
        PunktabstandEditFieldLabel     matlab.ui.control.Label
        PunktabstandEditField          matlab.ui.control.NumericEditField
        neuesEndstckTabelleleerenButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, WS)
            load('Settings\Endstuecknames.mat');
            app.bestehendeEndstckformenDropDown.Items = Endstuecknames;
            app.ElementhinzufgenDropDown.Items = {'Kegel'};
            app.Wandstaerke_EF.Value = WS;
            app.Wandstaerke_Hauptmodell_LB.Text = strcat(mat2str(WS),' mm');
            hold(app.UIAxes);
        end

        % Button pushed function: FormlschenButton
        function FormlschenButtonPushed(app, event)
            load('Settings\Endstuecknames.mat');
            SettingIndex = find(strcmp(Endstuecknames,app.bestehendeEndstckformenDropDown.Value));
            Endstuecknames(SettingIndex) = [];
            save('Settings\Endstuecknames.mat','Endstuecknames');
            delete(strcat('Settings\',app.bestehendeEndstckformenDropDown.Value,'.mat'));
            delete(strcat('Settings\',app.bestehendeEndstckformenDropDown.Value,'_P.mat'));
            delete(strcat('Settings\',app.bestehendeEndstckformenDropDown.Value,'_AllgEinst.mat'));
            app.bestehendeEndstckformenDropDown.Items = Endstuecknames;
        end

        % Value changed function: ElementhinzufgenDropDown
        function ElementhinzufgenDropDownValueChanged(app, event)
            value = app.ElementhinzufgenDropDown.Value;
            if isequal(value,'Kreisbogen')
                app.DurchmesserEditFieldLabel.Text = 'Radius';
                app.WinkelEditFieldLabel.Text = 'Winkel';
            elseif isequal(value,'Strecke')
                app.DurchmesserEditFieldLabel.Text = 'Länge';
                app.WinkelEditFieldLabel.Text = 'Winkel';
            elseif isequal(value,'Kegel')
                app.DurchmesserEditFieldLabel.Text = 'Durchmesser';
                app.WinkelEditFieldLabel.Text = 'Winkel';
            end
        end

        % Button pushed function: hinzufgenButton
        function hinzufgenButtonPushed(app, event)
            value = app.ElementhinzufgenDropDown.Value;
            if isequal(value,'Kreisbogen')
                if size(app.UITable.Data,1) ~= 0
                    app.UITable.Data(size(app.UITable.Data,1)+1,:) = {size(app.UITable.Data,1)+1,app.ElementhinzufgenDropDown.Value,app.DurchmesserEditField.Value,'-',app.WinkelEditField.Value,'-'};
                end
            elseif isequal(value,'Strecke')
                if size(app.UITable.Data,1) ~= 0
                    app.UITable.Data(size(app.UITable.Data,1)+1,:) = {size(app.UITable.Data,1)+1,app.ElementhinzufgenDropDown.Value,'-',app.DurchmesserEditField.Value,app.WinkelEditField.Value,'-'};
                end
            elseif isequal(value,'Kegel')
                if size(app.UITable.Data,1) == 0 && str2double(app.WinkelEditField.Value) > 0
                    app.UITable.Data = {1,app.ElementhinzufgenDropDown.Value,'-','-',app.WinkelEditField.Value,app.DurchmesserEditField.Value};
                    app.ElementhinzufgenDropDown.Items = {'Kreisbogen';'Strecke'};
                    app.DurchmesserEditFieldLabel.Text = 'Radius';
                    app.WinkelEditFieldLabel.Text = 'Winkel';
                end
            end

            % Plot updaten
            APPSCR_PlotUpdate
        end

        % Button pushed function: ndernButton
        function ndernButtonPushed(app, event)
            value = app.ElementhinzufgenDropDown.Value;
            Nummer = str2double(app.ElementNummerbearbeitenEditField.Value);
            if Nummer <= size(app.UITable.Data,1) && Nummer > 0
                if isequal(value,'Kreisbogen')
                    app.UITable.Data(Nummer,2:6) = {app.ElementhinzufgenDropDown.Value,app.DurchmesserEditField.Value,'-',app.WinkelEditField.Value,'-'};
                elseif isequal(value,'Strecke')
                    app.UITable.Data(Nummer,2:6) = {app.ElementhinzufgenDropDown.Value,'-',app.DurchmesserEditField.Value,app.WinkelEditField.Value,'-'};
                elseif isequal(value,'Kegel')
                    app.UITable.Data(Nummer,2:6) = {app.ElementhinzufgenDropDown.Value,'-','-',app.WinkelEditField.Value,app.DurchmesserEditField.Value};
                end
            end
            
            % Plot updaten
            APPSCR_PlotUpdate
        end

        % Button pushed function: lschenButton_2
        function lschenButton_2Pushed(app, event)
            Nummer = str2double(app.ElementNummerbearbeitenEditField.Value);
            if Nummer <= size(app.UITable.Data,1) && Nummer > 0
                app.UITable.Data(Nummer,:) = [];
            end
            for i = 1 : size(app.UITable.Data,1)
                app.UITable.Data(i,1) = {i};
            end
            % Dropdown updaten
            if size(app.UITable.Data,1) == 0
                app.ElementhinzufgenDropDown.Items = {'Kegel'};
                app.DurchmesserEditFieldLabel.Text = 'Durchmesser';
                app.WinkelEditFieldLabel.Text = 'Winkel';
            end

            % Plot updaten
            APPSCR_PlotUpdate
        end

        % Button pushed function: FormspeichernButton
        function FormspeichernButtonPushed(app, event)
            % Tabelleninformationen speichern
            Endstueck = app.UITable.Data;
            save(strcat('Settings\',app.NamederEndstckformEditField.Value,'.mat'),'Endstueck');
            
            % Allgemeine Einstellungen speichern
            PA = app.PunktabstandEditField.Value;
            WS = app.Wandstaerke_EF.Value;
            ES = app.EndstueckSchliessen_CB.Value;
            save(strcat('Settings\',app.NamederEndstckformEditField.Value,'_AllgEinst.mat'),'PA','WS','ES');
            
            % Liste der Endstücke speichern und Dropdown aktualisieren
            load('Settings\Endstuecknames.mat');
            Endstuecknames{size(Endstuecknames,1)+1,1} = app.NamederEndstckformEditField.Value;
            save(strcat('Settings\Endstuecknames.mat'),'Endstuecknames');
            app.bestehendeEndstckformenDropDown.Items = Endstuecknames;
            
            % Punktewolke speichern
            load('Settings\Punkte_Endstueck.mat');
            save(strcat('Settings\',app.NamederEndstckformEditField.Value,'_P.mat'),'P','Pa','Ps');
            
            % Meldung anzeigen
            APP_Eingabe_gespeichert
        end

        % Button pushed function: FormladenButton
        function FormladenButtonPushed(app, event)
            load(strcat('Settings\',app.bestehendeEndstckformenDropDown.Value,'.mat'));
            app.UITable.Data = Endstueck;
            
            % Allgemeine Einstellungen updaten
            load(strcat('Settings\',app.bestehendeEndstckformenDropDown.Value,'_AllgEinst.mat'),'PA','WS','ES');
            app.PunktabstandEditField.Value = PA;
            if WS > 0
                app.Wandstaerke_EF.Value = WS;
                app.Wandstaerke_EF.Enable = true;
                app.Wandstaerke_CB.Value = true;
            else
                app.Wandstaerke_EF.Value = '';
                app.Wandstaerke_EF.Enable = false;
                app.Wandstaerke_CB.Value = false;
            end
            app.EndstueckSchliessen_CB.Value = ES;
            
            % Auswahldropdown für Konstruktionselemente updaten
            if size(app.UITable.Data,1) > 0
                app.ElementhinzufgenDropDown.Items = {'Kreisbogen','Strecke'};
                app.DurchmesserEditFieldLabel.Text = 'Radius';
            else
                app.ElementhinzufgenDropDown.Items = {'Kegel'};
            end
            
            % Plot updaten
            APPSCR_PlotUpdate
        end

        % Button pushed function: neuesEndstckTabelleleerenButton
        function neuesEndstckTabelleleerenButtonPushed(app, event)
            app.UITable.Data = {};
            scatter(app.UIAxes,[],[],8,'filled');
            app.ElementhinzufgenDropDown.Items = {'Kegel'};
            app.DurchmesserEditFieldLabel.Text = 'Durchmesser';
        end

        % Callback function
        function EditorschlieenButtonPushed(app, event)
            close(app)
        end

        % Value changed function: Wandstaerke_CB
        function Wandstaerke_CBValueChanged(app, event)
            value = app.Wandstaerke_CB.Value;
            app.Wandstaerke_EF.Enable = value;
            app.Wandstaerke_mm.Enable = value;
        end

        % Button pushed function: PlotaktualisierenButton
        function PlotaktualisierenButtonPushed(app, event)
%             scatter(app.UIAxes,[],[],8,'filled');
            APPSCR_PlotUpdate
        end

        % Value changed function: EndstueckSchliessen_CB
        function EndstueckSchliessen_CBValueChanged(app, event)
            APPSCR_PlotUpdate
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Endstückeditor';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'#'; 'Form'; 'Radius'; 'Länge'; 'Winkel'; 'Durchmesser'};
            app.UITable.RowName = {};
            app.SpeichernundLadenvonEinstellungenPanel = uipanel(app.UIFigure);
            app.SpeichernundLadenvonEinstellungenPanel.Title = 'Speichern und Laden von Einstellungen';
            app.FormladenButton = uibutton(app.SpeichernundLadenvonEinstellungenPanel, 'push');
            app.FormladenButton.ButtonPushedFcn = createCallbackFcn(app, @FormladenButtonPushed, true);
            app.FormladenButton.Text = 'Form laden';
            app.FormlschenButton = uibutton(app.SpeichernundLadenvonEinstellungenPanel, 'push');
            app.FormlschenButton.ButtonPushedFcn = createCallbackFcn(app, @FormlschenButtonPushed, true);
            app.FormlschenButton.Text = 'Form löschen';
            app.FormspeichernButton = uibutton(app.SpeichernundLadenvonEinstellungenPanel, 'push');
            app.FormspeichernButton.ButtonPushedFcn = createCallbackFcn(app, @FormspeichernButtonPushed, true);
            app.FormspeichernButton.Text = 'Form speichern';
            app.bestehendeEndstckformenDropDownLabel = uilabel(app.SpeichernundLadenvonEinstellungenPanel);
            app.bestehendeEndstckformenDropDownLabel.Text = 'bestehende Endstückformen';
            app.bestehendeEndstckformenDropDown = uidropdown(app.SpeichernundLadenvonEinstellungenPanel);
            app.NamederEndstckformEditFieldLabel = uilabel(app.SpeichernundLadenvonEinstellungenPanel);
            app.NamederEndstckformEditFieldLabel.Text = 'Name der Endstückform';
            app.NamederEndstckformEditField = uieditfield(app.SpeichernundLadenvonEinstellungenPanel, 'text');
            app.KonstruktionselementehinzufgenndernoderlschenPanel = uipanel(app.UIFigure);
            app.KonstruktionselementehinzufgenndernoderlschenPanel.Title = 'Konstruktionselemente hinzufügen, ändern oder löschen';
            app.hinzufgenButton = uibutton(app.KonstruktionselementehinzufgenndernoderlschenPanel, 'push');
            app.hinzufgenButton.ButtonPushedFcn = createCallbackFcn(app, @hinzufgenButtonPushed, true);
            app.hinzufgenButton.Text = 'hinzufügen';
            app.ElementhinzufgenDropDownLabel = uilabel(app.KonstruktionselementehinzufgenndernoderlschenPanel);
            app.ElementhinzufgenDropDownLabel.Text = 'Element hinzufügen';
            app.ElementhinzufgenDropDown = uidropdown(app.KonstruktionselementehinzufgenndernoderlschenPanel);
            app.ElementhinzufgenDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementhinzufgenDropDownValueChanged, true);
            app.DurchmesserEditFieldLabel = uilabel(app.KonstruktionselementehinzufgenndernoderlschenPanel);
            app.DurchmesserEditFieldLabel.Text = 'Durchmesser';
            app.DurchmesserEditField = uieditfield(app.KonstruktionselementehinzufgenndernoderlschenPanel, 'text');
            app.WinkelEditFieldLabel = uilabel(app.KonstruktionselementehinzufgenndernoderlschenPanel);
            app.WinkelEditFieldLabel.Text = 'Winkel';
            app.WinkelEditField = uieditfield(app.KonstruktionselementehinzufgenndernoderlschenPanel, 'text');
            app.grad = uilabel(app.KonstruktionselementehinzufgenndernoderlschenPanel);
            app.grad.Text = '[°]';
            app.mm = uilabel(app.KonstruktionselementehinzufgenndernoderlschenPanel);
            app.mm.Text = '[mm]';
            app.ndernButton = uibutton(app.KonstruktionselementehinzufgenndernoderlschenPanel, 'push');
            app.ndernButton.ButtonPushedFcn = createCallbackFcn(app, @ndernButtonPushed, true);
            app.ndernButton.Text = 'ändern';
            app.ElementNummerbearbeitenEditFieldLabel = uilabel(app.KonstruktionselementehinzufgenndernoderlschenPanel);
            app.ElementNummerbearbeitenEditFieldLabel.Text = 'Element Nummer           bearbeiten';
            app.ElementNummerbearbeitenEditField = uieditfield(app.KonstruktionselementehinzufgenndernoderlschenPanel, 'text');
            app.lschenButton_2 = uibutton(app.KonstruktionselementehinzufgenndernoderlschenPanel, 'push');
            app.lschenButton_2.ButtonPushedFcn = createCallbackFcn(app, @lschenButton_2Pushed, true);
            app.lschenButton_2.Text = 'löschen';
            app.AllgemeineEinstellungenPanel = uipanel(app.UIFigure);
            app.AllgemeineEinstellungenPanel.Title = 'Allgemeine Einstellungen';
            app.AnzahlderGenerationenEditFieldLabel_4 = uilabel(app.AllgemeineEinstellungenPanel);
            app.AnzahlderGenerationenEditFieldLabel_4.Text = '[µm]';
            app.Wandstaerke_mm = uilabel(app.AllgemeineEinstellungenPanel);
            app.Wandstaerke_mm.Enable = 'off';
            app.Wandstaerke_mm.Text = '[mm]';
            app.EndstueckSchliessen_CB = uicheckbox(app.AllgemeineEinstellungenPanel);
            app.EndstueckSchliessen_CB.ValueChangedFcn = createCallbackFcn(app, @EndstueckSchliessen_CBValueChanged, true);
            app.EndstueckSchliessen_CB.Text = 'Endstück schließen';
            app.Wandstaerke_CB = uicheckbox(app.AllgemeineEinstellungenPanel);
            app.Wandstaerke_CB.ValueChangedFcn = createCallbackFcn(app, @Wandstaerke_CBValueChanged, true);
            app.Wandstaerke_CB.Text = 'Wandstärke konstruieren (nach außen)';
            app.Wandstaerke_EF = uieditfield(app.AllgemeineEinstellungenPanel, 'numeric');
            app.Wandstaerke_EF.Enable = 'off';
            app.Wandstaerke_Hauptmodell_LB = uilabel(app.AllgemeineEinstellungenPanel);
            app.Wandstaerke_Hauptmodell_LB.Text = '';
            app.WS_HM_Label = uilabel(app.AllgemeineEinstellungenPanel);
            app.WS_HM_Label.Text = 'Wandstärke des Hauptmodells:';
            app.PlotaktualisierenButton = uibutton(app.AllgemeineEinstellungenPanel, 'push');
            app.PlotaktualisierenButton.ButtonPushedFcn = createCallbackFcn(app, @PlotaktualisierenButtonPushed, true);
            app.PlotaktualisierenButton.Text = 'Plot aktualisieren';
            app.PunktabstandEditFieldLabel = uilabel(app.AllgemeineEinstellungenPanel);
            app.PunktabstandEditFieldLabel.Text = 'Punktabstand';
            app.PunktabstandEditField = uieditfield(app.AllgemeineEinstellungenPanel, 'numeric');
            app.PunktabstandEditField.Value = 20;
            app.neuesEndstckTabelleleerenButton = uibutton(app.UIFigure, 'push');
            app.neuesEndstckTabelleleerenButton.ButtonPushedFcn = createCallbackFcn(app, @neuesEndstckTabelleleerenButtonPushed, true);
            app.neuesEndstckTabelleleerenButton.Text = 'neues Endstück (Tabelle leeren)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_Endstueckeditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end