classdef APP_Ergebnis_speichern < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EingabenspeichernUIFigure     matlab.ui.Figure
        speichernButton               matlab.ui.control.Button
        verwerfenButton               matlab.ui.control.Button
        DateinameEditFieldLabel       matlab.ui.control.Label
        DateinameEditField            matlab.ui.control.EditField
        SollderaktuelleBronchialbaumgespeichertwerdenLabel  matlab.ui.control.Label
        DateinameEditField_2Label     matlab.ui.control.Label
        DateinameEditField_2          matlab.ui.control.EditField
        ErgebnisseLabel               matlab.ui.control.Label
        matLabel                      matlab.ui.control.Label
        matLabel_2                    matlab.ui.control.Label
        VollstndigesErgebnisCheckBox  matlab.ui.control.CheckBox
        ErgebnisseLabel_2             matlab.ui.control.Label
        ErgebnisseLabel_3             matlab.ui.control.Label
        txtLabel                      matlab.ui.control.Label
        ErgebnisalstxtDateiCheckBox   matlab.ui.control.CheckBox
        ErgebnisseLabel_4             matlab.ui.control.Label
        DateinameEditField_3Label     matlab.ui.control.Label
        DateinameEditField_3          matlab.ui.control.EditField
        EingbeparameterCheckBox       matlab.ui.control.CheckBox
        ErgebnisseLabel_5             matlab.ui.control.Label
        ErgebnisseLabel_6             matlab.ui.control.Label
        ErgebnisseLabel_7             matlab.ui.control.Label
        ptsLabel                      matlab.ui.control.Label
        ErgebnisalsptsDateiCheckBox   matlab.ui.control.CheckBox
        ErgebnisseLabel_8             matlab.ui.control.Label
        DateinameEditField_4Label     matlab.ui.control.Label
        DateinameEditField_4          matlab.ui.control.EditField
        FehlerberichtLabel            matlab.ui.control.Label
        Fehlerliste                   matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Initialisierung(app)
            load ('VAR_NBB.mat','N_BB');
            load ('VAR_temp.mat');
            app.DateinameEditField.Value = strcat('G',num2str(Gen),'_ME',num2str(D{1,1}),'_#',num2str(N_BB+1));
            app.DateinameEditField_3.Value = strcat('G',num2str(Gen),'_ME',num2str(D{1,1}),'_#',num2str(N_BB+1),'_Modell');
            app.DateinameEditField_4.Value = strcat('G',num2str(Gen),'_ME',num2str(D{1,1}),'_#',num2str(N_BB+1),'_Modell');
            app.DateinameEditField_2.Value = strcat('G',num2str(Gen),'_ME',num2str(D{1,1}),'_#',num2str(N_BB+1),'_Eingabe');
            i = 0;
            if Errors(1) == true
                app.Fehlerliste.Data = {'EC001','Anschlusspunktverschiebung'};
                i = i + 1;
            end
            if Errors(2) == true && isempty(app.Fehlerliste.Data)
                app.Fehlerliste.Data = {'EC002','Kollision'};
                i = i + 1;
            elseif Errors(2) == true && isempty(app.Fehlerliste.Data) == false
                app.Fehlerliste.Data(i+1,:) = {'EC002','Kollision'};
                i = i + 1;

            end
            if Errors(3) == true && isempty(app.Fehlerliste.Data)
                app.Fehlerliste.Data = {'EC003','Innenwandfehler'};
                i = i + 1;
            elseif Errors(3) == true && isempty(app.Fehlerliste.Data) == false
                app.Fehlerliste.Data(i+1,:) = {'EC003','Innenwandfehler'};
                i = i + 1;
            end
        end

        % Button pushed function: speichernButton
        function Speichern(app, event)
            load ('VAR_NBB.mat','N_BB');
            load ('VAR_temp.mat');
            if app.VollstndigesErgebnisCheckBox.Value == true
                save(strcat('Ergebnisse\',app.DateinameEditField.Value,'.mat'),'Alpha','Beta','D','L','Gen','M','N','P','Param_Modell','Param_RE','Param_VE');
            end
            if app.ErgebnisalstxtDateiCheckBox.Value == true
                save(strcat('Ergebnisse\',app.DateinameEditField_3.Value,'.txt'),'P','-ascii');
            end
            if app.ErgebnisalsptsDateiCheckBox.Value == true
                save(strcat('Ergebnisse\',app.DateinameEditField_4.Value,'.pts'),'P','-ascii');
            end
            if app.EingbeparameterCheckBox.Value == true
                save(strcat('Eingabe\',app.DateinameEditField_2.Value,'.mat'),'Gen','D','L','Alpha','Beta'); %,'BL')
            end
            N_BB = N_BB + 1;
            save('VAR_NBB.mat','N_BB');
            clear('N_BB')
            app.delete
        end

        % Button pushed function: verwerfenButton
        function nicht_speichern(app, event)
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EingabenspeichernUIFigure = uifigure('Visible', 'off');
            app.EingabenspeichernUIFigure.Name = 'Eingaben speichern';
            app.speichernButton = uibutton(app.EingabenspeichernUIFigure, 'push');
            app.speichernButton.ButtonPushedFcn = createCallbackFcn(app, @Speichern, true);
            app.speichernButton.Text = 'speichern';
            app.verwerfenButton = uibutton(app.EingabenspeichernUIFigure, 'push');
            app.verwerfenButton.ButtonPushedFcn = createCallbackFcn(app, @nicht_speichern, true);
            app.verwerfenButton.Text = 'verwerfen';
            app.DateinameEditFieldLabel = uilabel(app.EingabenspeichernUIFigure);
            app.DateinameEditFieldLabel.Text = 'Dateiname';
            app.DateinameEditField = uieditfield(app.EingabenspeichernUIFigure, 'text');
            app.DateinameEditField.Value = 'vollst_Ergebnis';
            app.SollderaktuelleBronchialbaumgespeichertwerdenLabel = uilabel(app.EingabenspeichernUIFigure);
            app.SollderaktuelleBronchialbaumgespeichertwerdenLabel.Text = 'Soll der aktuelle Bronchialbaum gespeichert werden?';
            app.DateinameEditField_2Label = uilabel(app.EingabenspeichernUIFigure);
            app.DateinameEditField_2Label.Text = 'Dateiname';
            app.DateinameEditField_2 = uieditfield(app.EingabenspeichernUIFigure, 'text');
            app.DateinameEditField_2.Value = 'Eingabeparam';
            app.ErgebnisseLabel = uilabel(app.EingabenspeichernUIFigure);
            app.ErgebnisseLabel.Text = 'Diese Option speichert alle Variablen, die bei der Berechnung';
            app.matLabel = uilabel(app.EingabenspeichernUIFigure);
            app.matLabel.Text = '.mat';
            app.matLabel_2 = uilabel(app.EingabenspeichernUIFigure);
            app.matLabel_2.Text = '.mat';
            app.VollstndigesErgebnisCheckBox = uicheckbox(app.EingabenspeichernUIFigure);
            app.VollstndigesErgebnisCheckBox.Text = 'Vollständiges Ergebnis';
            app.VollstndigesErgebnisCheckBox.Value = true;
            app.ErgebnisseLabel_2 = uilabel(app.EingabenspeichernUIFigure);
            app.ErgebnisseLabel_2.Text = 'des Bronchialbaumes erzeugt wurden.';
            app.ErgebnisseLabel_3 = uilabel(app.EingabenspeichernUIFigure);
            app.ErgebnisseLabel_3.Text = 'Diese Option speichert nur das Bronchialbaummodell als';
            app.txtLabel = uilabel(app.EingabenspeichernUIFigure);
            app.txtLabel.Text = '.txt';
            app.ErgebnisalstxtDateiCheckBox = uicheckbox(app.EingabenspeichernUIFigure);
            app.ErgebnisalstxtDateiCheckBox.Text = 'Ergebnis als .txt -Datei';
            app.ErgebnisalstxtDateiCheckBox.Value = true;
            app.ErgebnisseLabel_4 = uilabel(app.EingabenspeichernUIFigure);
            app.ErgebnisseLabel_4.Text = 'Textdatei für einen Import, z.B. eine CAD-Software.';
            app.DateinameEditField_3Label = uilabel(app.EingabenspeichernUIFigure);
            app.DateinameEditField_3Label.Text = 'Dateiname';
            app.DateinameEditField_3 = uieditfield(app.EingabenspeichernUIFigure, 'text');
            app.DateinameEditField_3.Value = 'Modell';
            app.EingbeparameterCheckBox = uicheckbox(app.EingabenspeichernUIFigure);
            app.EingbeparameterCheckBox.Text = 'Eingbeparameter';
            app.EingbeparameterCheckBox.Value = true;
            app.ErgebnisseLabel_5 = uilabel(app.EingabenspeichernUIFigure);
            app.ErgebnisseLabel_5.Text = 'Diese Option speichert die Eingabeparameter als Matlab-';
            app.ErgebnisseLabel_6 = uilabel(app.EingabenspeichernUIFigure);
            app.ErgebnisseLabel_6.Text = 'variable, um das Modell später reproduzieren zu können.';
            app.ErgebnisseLabel_7 = uilabel(app.EingabenspeichernUIFigure);
            app.ErgebnisseLabel_7.Text = 'Diese Option speichert nur das Bronchialbaummodell als';
            app.ptsLabel = uilabel(app.EingabenspeichernUIFigure);
            app.ptsLabel.Text = '.pts';
            app.ErgebnisalsptsDateiCheckBox = uicheckbox(app.EingabenspeichernUIFigure);
            app.ErgebnisalsptsDateiCheckBox.Text = 'Ergebnis als .pts -Datei';
            app.ErgebnisalsptsDateiCheckBox.Value = true;
            app.ErgebnisseLabel_8 = uilabel(app.EingabenspeichernUIFigure);
            app.ErgebnisseLabel_8.Text = 'Textdatei für einen Import, z.B. eine CAD-Software.';
            app.DateinameEditField_4Label = uilabel(app.EingabenspeichernUIFigure);
            app.DateinameEditField_4Label.Text = 'Dateiname';
            app.DateinameEditField_4 = uieditfield(app.EingabenspeichernUIFigure, 'text');
            app.DateinameEditField_4.Value = 'Modell';
            app.FehlerberichtLabel = uilabel(app.EingabenspeichernUIFigure);
            app.FehlerberichtLabel.Text = 'Fehlerbericht';
            app.Fehlerliste = uitable(app.EingabenspeichernUIFigure);
            app.Fehlerliste.ColumnName = {'Fehlercode'; 'Beschreibung'};
            app.Fehlerliste.RowName = {};
            % Show the figure after all components are created
            app.EingabenspeichernUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_Ergebnis_speichern

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EingabenspeichernUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Initialisierung)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EingabenspeichernUIFigure)
        end
    end
end