classdef APP_Hauptmenue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HauptmenBronchialbaummodellUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AllgemeineModelleigenschaftenTab  matlab.ui.container.Tab
        UrsprungvonGeometrieparameterwertenButtonGroup  matlab.ui.container.ButtonGroup
        HauptgeometrieparameterausmDateivorgebenButton  matlab.ui.control.RadioButton
        zuflligverteilteParametererzeugenButton  matlab.ui.control.RadioButton
        NamedesUnterordnersimaktuellenVerzeichnisLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        NamederDateiwechedieEingabevariablenenthltLabel  matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        matLabel                        matlab.ui.control.Label
        DasEingabefeldGeometrieparametererzeugungbleibtLabel  matlab.ui.control.Label
        unbercksichtigtLabel            matlab.ui.control.Label
        DieEingabederstatistischenParameterimEingebfeldLabel  matlab.ui.control.Label
        GeometrieparametererzeugungisterforderlichLabel  matlab.ui.control.Label
        AuflsungButtonGroup             matlab.ui.container.ButtonGroup
        VerfeinerungsfaktorderCarinalkurvenauflsungEditFieldLabel  matlab.ui.control.Label
        VerfeinerungsfaktorderCarinalkurvenauflsungEditField  matlab.ui.control.NumericEditField
        MinimaleAuflsungvorgebenButton  matlab.ui.control.RadioButton
        Auflsungam1RohrelementinUmfangsrichtungEditFieldLabel  matlab.ui.control.Label
        Auflsungam1RohrelementinUmfangsrichtungEditField  matlab.ui.control.NumericEditField
        AnzahlderGenerationenEditFieldLabel_53  matlab.ui.control.Label
        PunktezahlinUmfangsrichtungButton  matlab.ui.control.RadioButton
        AnzahlderGenerationenEditFieldLabel_54  matlab.ui.control.Label
        PunktezahlEditFieldLabel        matlab.ui.control.Label
        PunktezahlEditField             matlab.ui.control.NumericEditField
        AnzahlderGenerationenEditFieldLabel_55  matlab.ui.control.Label
        KollisionenButtonGroup          matlab.ui.container.ButtonGroup
        VerdrehwinkeloptimierenCheckBox  matlab.ui.control.CheckBox
        GewichtungderDurchmesserEditFieldLabel  matlab.ui.control.Label
        GewichtungderDurchmesserEditField  matlab.ui.control.NumericEditField
        positivgroeDurchmesserwerdenstrkergewichtetLabel  matlab.ui.control.Label
        negativkleineDurchmesserwerdenstrkergewichtetLabel  matlab.ui.control.Label
        AnzahlderIterationenEditFieldLabel  matlab.ui.control.Label
        AnzahlderIterationenEditField   matlab.ui.control.NumericEditField
        positivgroeAbstndewerdenstrkergewichtetLabel  matlab.ui.control.Label
        negativgroeAbstndewerdenschwchergewichtetLabel  matlab.ui.control.Label
        GewichtungderAnschlusspunktAbstndeEditFieldLabel  matlab.ui.control.Label
        GewichtungderAnschlusspunktAbstndeEditField  matlab.ui.control.NumericEditField
        Einheit_VWopt_2                 matlab.ui.control.Label
        Einheit_VWopt_3                 matlab.ui.control.Label
        Einheit_VWopt_1                 matlab.ui.control.Label
        gewichtete_Abstandssummen_OB    matlab.ui.control.RadioButton
        kleinster_Abstand_OB            matlab.ui.control.RadioButton
        KollisionendetektierenCheckBox  matlab.ui.control.CheckBox
        Einheit_Kdet_1                  matlab.ui.control.Label
        FaktorzurAuflsungdesZentralachsmodellsEditFieldLabel  matlab.ui.control.Label
        FaktorzurAuflsungdesZentralachsmodellsEditField  matlab.ui.control.NumericEditField
        Einheit_Kdet_2                  matlab.ui.control.Label
        PufferAbstandbeiderKollisionsdetektionEditFieldLabel  matlab.ui.control.Label
        PufferAbstandbeiderKollisionsdetektionEditField  matlab.ui.control.NumericEditField
        GeometrieparametererzeugungTab  matlab.ui.container.Tab
        allgemeineEigenschaftendesVerzweigungsmodellsPanel  matlab.ui.container.Panel
        AnzahlderGenerationenEditFieldLabel_7  matlab.ui.control.Label
        AnzahlderGenerationenEditFieldLabel_8  matlab.ui.control.Label
        AnzahlderGenerationenEditFieldLabel_56  matlab.ui.control.Label
        AnzahlderGenerationenEditField  matlab.ui.control.NumericEditField
        DurchmesserdeserstenMutterastesEditFieldLabel  matlab.ui.control.Label
        DurchmesserdeserstenMutterastesEditField  matlab.ui.control.NumericEditField
        AnzahlderGenerationenEditFieldLabel_30  matlab.ui.control.Label
        LngedeserstenMutterastesEditFieldLabel  matlab.ui.control.Label
        LngedeserstenMutterastesEditField  matlab.ui.control.NumericEditField
        AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup  matlab.ui.container.ButtonGroup
        normalverteiltpositivkorreliertButton  matlab.ui.control.RadioButton
        AnzahlderGenerationenEditFieldLabel_15  matlab.ui.control.Label
        AnzahlderGenerationenEditFieldLabel_16  matlab.ui.control.Label
        VarianzinitialEditField_3Label  matlab.ui.control.Label
        VarianzinitialEditField_3       matlab.ui.control.NumericEditField
        ErwartungswertLngexDurchmesserEditFieldLabel  matlab.ui.control.Label
        ErwartungswertLngexDurchmesserEditField  matlab.ui.control.NumericEditField
        AnzahlderGenerationenEditFieldLabel_39  matlab.ui.control.Label
        AnzahlderGenerationenEditFieldLabel_40  matlab.ui.control.Label
        minimaleLngeLngexDurchmesserEditFieldLabel  matlab.ui.control.Label
        minimaleLngeLngexDurchmesserEditField  matlab.ui.control.NumericEditField
        maximaleLngeLngexDurchmesserEditFieldLabel  matlab.ui.control.Label
        maximaleLngeLngexDurchmesserEditField  matlab.ui.control.NumericEditField
        normalverteiltLabel             matlab.ui.control.Label
        NenndurchmesserderBronchienButtonGroup  matlab.ui.container.ButtonGroup
        normalverteiltButton            matlab.ui.control.RadioButton
        AnzahlderGenerationenEditFieldLabel_19  matlab.ui.control.Label
        ExponentnderungdesStrmungsquerschnittsEditFieldLabel  matlab.ui.control.Label
        ExponentnderungdesStrmungsquerschnittsEditField  matlab.ui.control.NumericEditField
        AnzahlderGenerationenEditFieldLabel_21  matlab.ui.control.Label
        VarianzinitialEditField_4Label  matlab.ui.control.Label
        VarianzinitialEditField_4       matlab.ui.control.NumericEditField
        normalverteiltLabel_2           matlab.ui.control.Label
        VerzweigungswinkelButtonGroup   matlab.ui.container.ButtonGroup
        AnzahlderGenerationenEditFieldLabel_4  matlab.ui.control.Label
        GrenzverzweigungswinkelEditFieldLabel  matlab.ui.control.Label
        GrenzverzweigungswinkelEditField  matlab.ui.control.NumericEditField
        AnzahlderGenerationenEditFieldLabel_31  matlab.ui.control.Label
        exponentiellmitExponentxButton  matlab.ui.control.RadioButton
        VerzweigungswinkelmitExponentEditFieldLabel  matlab.ui.control.Label
        VerzweigungswinkelmitExponentEditField  matlab.ui.control.NumericEditField
        exponentiellerZusammenhangzwischenDurchmesserundLabel  matlab.ui.control.Label
        AnzahlderGenerationenEditFieldLabel_57  matlab.ui.control.Label
        LagederBifurkationsebenenButtonGroup  matlab.ui.container.ButtonGroup
        AlleBifurkationsbenenliegenineinergemeinsamenEbeneButton  matlab.ui.control.RadioButton
        JedeBifurkationerhlteineeigeneBifurkationsebeneButton  matlab.ui.control.RadioButton
        BifurkationsmodellTab           matlab.ui.container.Tab
        VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup  matlab.ui.container.ButtonGroup
        VerhltnisKrmmungsradiusRohrdurchmesserEditField_2  matlab.ui.control.NumericEditField
        VerhltnisKrmmungsradiusRohrdurchmesserEditField_3  matlab.ui.control.NumericEditField
        DLabel                          matlab.ui.control.Label
        ParameterderPolynomfunktionLabel  matlab.ui.control.Label
        MLabel                          matlab.ui.control.Label
        VerhltnisKrmmungsradiusRohrdurchmesserEditField_4  matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        DLabel_3                        matlab.ui.control.Label
        VerhltnisKrmmungsradiusRohrdurchmesserEditField_5  matlab.ui.control.NumericEditField
        DLabel_2                        matlab.ui.control.Label
        VerhltnisKrmmungsradiusRohrdurchmesserEditField_6  matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        VerrundungsparametereinhaltenButton  matlab.ui.control.RadioButton
        LngederBronchieneinhaltennurbeivorgegebenenButton  matlab.ui.control.RadioButton
        AnzahlderGenerationenEditFieldLabel_50  matlab.ui.control.Label
        AnteildesgekrmmtenAbschnittsEditFieldLabel  matlab.ui.control.Label
        AnteildesgekrmmtenAbschnittsEditField  matlab.ui.control.NumericEditField
        HauptgeometrieparameternvglAllgemeineModelleigenschaftenLabel  matlab.ui.control.Label
        KxLabel                         matlab.ui.control.Label
        iLabel                          matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        iLabel_2                        matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        CarinalverrundungButtonGroup    matlab.ui.container.ButtonGroup
        AnzahlderGenerationenEditFieldLabel_29  matlab.ui.control.Label
        EinflussderTochterdurchmesserEditFieldLabel  matlab.ui.control.Label
        EinflussderTochterdurchmesserEditField  matlab.ui.control.NumericEditField
        AnzahlderGenerationenEditFieldLabel_32  matlab.ui.control.Label
        EinflussderVerzweigungswinkelEditFieldLabel  matlab.ui.control.Label
        EinflussderVerzweigungswinkelEditField  matlab.ui.control.NumericEditField
        AnzahlderGenerationenEditFieldLabel_38  matlab.ui.control.Label
        BreitederCarinalkurveEditFieldLabel  matlab.ui.control.Label
        BreitederCarinalkurveEditField  matlab.ui.control.NumericEditField
        SigmoidfunktionButtonGroup_2    matlab.ui.container.ButtonGroup
        modifiziertButton               matlab.ui.control.RadioButton
        SigmoidfunktionnachHeistracherundHofmann1995Button  matlab.ui.control.RadioButton
        AnzahlderGenerationenEditFieldLabel_36  matlab.ui.control.Label
        ModulationsparameterEditField_5Label  matlab.ui.control.Label
        ModulationsparameterEditField_5  matlab.ui.control.NumericEditField
        AnzahlderGenerationenEditFieldLabel_37  matlab.ui.control.Label
        ModulationsparameterEditField_6Label  matlab.ui.control.Label
        ModulationsparameterEditField_6  matlab.ui.control.NumericEditField
        PostProcessingTab               matlab.ui.container.Tab
        EndstckeButtonGroup             matlab.ui.container.ButtonGroup
        AnzahlderGenerationenEditFieldLabel_41  matlab.ui.control.Label
        LngeEditFieldLabel              matlab.ui.control.Label
        Laenge_Endstueck_NEF            matlab.ui.control.NumericEditField
        geradeZylinderButton            matlab.ui.control.RadioButton
        keineButton                     matlab.ui.control.RadioButton
        benutzerdefiniertButton         matlab.ui.control.RadioButton
        EndstckeditorstartenButton      matlab.ui.control.Button
        benutzerdefiniertes_Endstueck_DD  matlab.ui.control.DropDown
        aktualisierenButton             matlab.ui.control.Button
        SockelButtonGroup               matlab.ui.container.ButtonGroup
        HheEditFieldLabel               matlab.ui.control.Label
        Hoehe_Sockel_EF                 matlab.ui.control.NumericEditField
        AnzahlderGenerationenEditFieldLabel_42  matlab.ui.control.Label
        AnzahlderGenerationenEditFieldLabel_43  matlab.ui.control.Label
        DurchmesserEditFieldLabel       matlab.ui.control.Label
        Durchmesser_Sockel_EF           matlab.ui.control.NumericEditField
        SockelkonstruierenCheckBox      matlab.ui.control.CheckBox
        WandstrkeButtonGroup            matlab.ui.container.ButtonGroup
        WandstrkeEditFieldLabel         matlab.ui.control.Label
        WandstrkeEditField              matlab.ui.control.NumericEditField
        WandstrkenachinnenauftragenButton  matlab.ui.control.RadioButton
        AnzahlderGenerationenEditFieldLabel_51  matlab.ui.control.Label
        WandstrkenachauenauftragenButton  matlab.ui.control.RadioButton
        WandstrkekonstruierenCheckBox   matlab.ui.control.CheckBox
        CarinalverrundunganpassenCheckBox  matlab.ui.control.CheckBox
        FehlertoleranzEditFieldLabel    matlab.ui.control.Label
        FehlertoleranzEditField         matlab.ui.control.NumericEditField
        AnzahlderGenerationenEditFieldLabel_52  matlab.ui.control.Label
        ModelloberflcheamallenffnungenschlieenCheckBox  matlab.ui.control.CheckBox
        BerechnungstartenButton         matlab.ui.control.Button
        SpeichernundLadenvonEinstellungenPanel  matlab.ui.container.Panel
        SettingladenButton              matlab.ui.control.Button
        SettingspeichernButton          matlab.ui.control.Button
        SettinglschenButton             matlab.ui.control.Button
        bestehendeSettingsDropDownLabel  matlab.ui.control.Label
        bestehendeSettingsDropDown      matlab.ui.control.DropDown
        NamedesSettingsEditFieldLabel   matlab.ui.control.Label
        NamedesSettingsEditField        matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('Settings\Settingnames.mat');
            app.bestehendeSettingsDropDown.Items = Settingnames;
            load('Settings\Endstuecknames.mat');
            app.benutzerdefiniertes_Endstueck_DD.Items = Endstuecknames;
        end

        % Button pushed function: BerechnungstartenButton
        function FNC_Main(app, event)
            % Informationen zur Auflösung:
            %   N(1) = Punktezahl in Umfangsrichtung
            %   N(2) = Verfeinerungsfaktor der Carinalverrundung
            %   N(3) = Punktezahl in Umfangsrichtung des Sockels
            if app.MinimaleAuflsungvorgebenButton.Value == true
                N = [FNC_Resolution6(pi*app.DurchmesserdeserstenMutterastesEditField.Value/(app.Auflsungam1RohrelementinUmfangsrichtungEditField.Value/1000)), ...
                    app.VerfeinerungsfaktorderCarinalkurvenauflsungEditField.Value,...
                    FNC_Resolution6(pi*app.Durchmesser_Sockel_EF.Value/(app.Auflsungam1RohrelementinUmfangsrichtungEditField.Value/1000))];
            elseif app.PunktezahlinUmfangsrichtungButton.Value == true
                N = [FNC_Resolution6(app.PunktezahlEditField.Value), ...
                    app.VerfeinerungsfaktorderCarinalkurvenauflsungEditField.Value,...
                    FNC_Resolution6(app.PunktezahlEditField.Value*(app.Durchmesser_Sockel_EF.Value/app.DurchmesserdeserstenMutterastesEditField.Value))];
            end
            
            % Informationen zur Erzeugung von Rohrelementparametern (Durchmesser, Längen, Winkel)
            if app.HauptgeometrieparameterausmDateivorgebenButton.Value == true
                Eingabemodus = 'm';    % m = manuell
                load(strcat(app.EditField.Value,'\',app.EditField_2.Value,'.mat'));
                Param_RE = {D,L,Alpha,Beta};
                ME = [D{1,1}(1), L{1,1}(1)];
                Param_Modell = 0;
                Gen = size(D,1);
            elseif app.zuflligverteilteParametererzeugenButton.Value == true
                Eingabemodus = 'a';    % a = automatisch
                if app.AlleBifurkationsbenenliegenineinergemeinsamenEbeneButton.Value == true
                    Param_Modell = 2;
                elseif app.JedeBifurkationerhlteineeigeneBifurkationsebeneButton.Value == true
                    Param_Modell = 3;
                end
                Gen = app.AnzahlderGenerationenEditField.Value;
                ME = [app.DurchmesserdeserstenMutterastesEditField.Value, ...
                    app.LngedeserstenMutterastesEditField.Value];
                Param_D = [app.VarianzinitialEditField_4.Value, ...
                    app.ExponentnderungdesStrmungsquerschnittsEditField.Value];
                % Länge: Vermeidung von Eigabefehlern
                L_min = min(app.minimaleLngeLngexDurchmesserEditField.Value,app.maximaleLngeLngexDurchmesserEditField.Value);
                L_max = max(app.minimaleLngeLngexDurchmesserEditField.Value,app.maximaleLngeLngexDurchmesserEditField.Value);
                if L_min < 1e-3 && L_max > 1e-3
                    L_min = 1e-3;
                elseif L_min < 1e-3 && L_max < 1e-3
                    L_min = 1e-3;
                    L_max = 1e-3;
                end
                if app.ErwartungswertLngexDurchmesserEditField.Value > L_min && app.ErwartungswertLngexDurchmesserEditField.Value < L_max
                    Param_L = [app.VarianzinitialEditField_3.Value,app.ErwartungswertLngexDurchmesserEditField.Value,L_min,L_max];
                else
                    Param_L = [app.VarianzinitialEditField_3.Value,(L_min+L_max)/2,L_min,L_max];
                end
                if app.exponentiellmitExponentxButton.Value == true
                    Param_Alpha = [app.GrenzverzweigungswinkelEditField.Value, app.VerzweigungswinkelmitExponentEditField.Value,1];
                elseif app.AbhngigkeitvomDurchmessernachPolynomfktmitGradxButton.Value == true
                    Param_Alpha = [app.GrenzverzweigungswinkelEditField.Value, app.VWinkelbei2gleichenTochterelementenEditField.Value,2];
                end
%                 Param_BL = [app.OrdnungderAbhngigkeitvomDurchmesserEditField.Value,app.FaktorielleAbhngigkeitvomDurchmesserEditField.Value,app.AnteilgekrmmterAbschnittEditField.Value];
                Param_RE = {[2,2];Param_D;Param_L;Param_Alpha};
            end

        % Informationen zum Verzweigungsmodell
            Param_K = {app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_5.Value, ...
                app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_6.Value, ...
                app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_2.Value, ...
                [1,1]*app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_3.Value, ...
                app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_4.Value, ...
                app.LngederBronchieneinhaltennurbeivorgegebenenButton.Value, ...
                app.AnteildesgekrmmtenAbschnittsEditField.Value};
            Param_C = [app.EinflussderTochterdurchmesserEditField.Value, ...
                app.EinflussderVerzweigungswinkelEditField.Value, ...
                app.BreitederCarinalkurveEditField.Value];
            if app.SigmoidfunktionnachHeistracherundHofmann1995Button.Value == true
                Param_S = [2,app.ModulationsparameterEditField_5.Value];
            elseif app.modifiziertButton.Value == true
                Param_S = [3,app.ModulationsparameterEditField_6.Value];
            end
            Param_VE = {Param_K;Param_C;Param_S};
            
        % Informationen zum Post Processing
          % Sockel und Terminalverlängerungen
            if app.keineButton.Value == true
                Param_PP{1} = 0;
            elseif app.geradeZylinderButton.Value == 1
                Param_PP{1} = 1;
            elseif app.benutzerdefiniertButton.Value == 1
                Param_PP{1} = 2;
            end
            Param_PP = {Param_PP{1}, ...
                0, ...  % Dieser Slot wurde für eine frühere Einstellung verwendet
                app.SockelkonstruierenCheckBox.Value, ...   
                app.Hoehe_Sockel_EF.Value, ...
                app.Durchmesser_Sockel_EF.Value, ...
                app.ModelloberflcheamallenffnungenschlieenCheckBox.Value};
          % Endstücke
            if app.keineButton.Value == true
                Param_AE{1} = 0;
            elseif app.geradeZylinderButton.Value == true
                Param_AE{1} = 1;
                Param_AE{2} = app.Laenge_Endstueck_NEF.Value;
            elseif app.benutzerdefiniertButton.Value == true
                Param_AE{1} = 2;
                load(strcat('Settings\',app.benutzerdefiniertes_Endstueck_DD.Value,'_P','.mat'));
                Param_AE{2} = P;
                Param_AE{3} = Pa;
                Param_AE{4} = Ps;
            end
          % Wandstärke
            if app.WandstrkenachauenauftragenButton.Value == true  % Falls die Richtung nach außen geht, ...
                WS_Richtung = 1;  % ist Param_WS(3) = 1, ...  
            elseif app.WandstrkenachinnenauftragenButton.Value == true  % falls sie nach innen geht, ...
                WS_Richtung = -1;  % ist Param_WS(3) = -1.
            end
            Param_WS = [app.WandstrkekonstruierenCheckBox.Value, ...
                app.WandstrkeEditField.Value, ...
                WS_Richtung, ...
                app.CarinalverrundunganpassenCheckBox.Value,...
                app.FehlertoleranzEditField.Value];
            
        % Informationen zur Kollisionsvermeidung
            if app.VerdrehwinkeloptimierenCheckBox.Value == true && app.gewichtete_Abstandssummen_OB.Value == true
                Param_KV(1,1) = 1;  % 1 indiziert Kollisionsvermeidung durch Minimerung der Abstandssumme ^-1
                Param_KV(2,1) = app.AnzahlderIterationenEditField.Value;
                Param_KV(3,1) = app.GewichtungderDurchmesserEditField.Value; 
                Param_KV(4,1) = app.GewichtungderAnschlusspunktAbstndeEditField.Value;
                Param_Modell = 2; % Alle Bifurkationsebenen liegen zunächst in einer gemeinsamen Ebene. Das entsprechende Feld im Eingabefeld "Geometrieparametererzeugung" wird automatisch entsprechend gesetzt.
            elseif app.VerdrehwinkeloptimierenCheckBox.Value == true && app.kleinster_Abstand_OB.Value == true
                Param_KV(1,1) = 2;  % 2 indiziert Kollisionsvermeidung durch Maximierung des kleinsten Abstands
                Param_KV(2,1) = app.AnzahlderIterationenEditField.Value;
                Param_KV(3,1) = NaN;
                Param_KV(4,1) = NaN;
            else
                Param_KV = [zeros(4,1)];
            end
            Param_KV = [Param_KV;...
                app.KollisionendetektierenCheckBox.Value;...
                app.FaktorzurAuflsungdesZentralachsmodellsEditField.Value;...
                app.PufferAbstandbeiderKollisionsdetektionEditField.Value];
        % Punkteerzeugung
            close all
            P = FNC_Erzeugung_VNW(N,Gen,ME,Param_RE,Param_VE,Param_Modell,Eingabemodus,Param_PP,Param_WS,Param_KV,0,Param_AE);
        end

        % Callback function
        function EingabeSpeichern(app, event)
            
        end

        % Callback function
        function EingabeLaden(app, event)
          
        end

        % Callback function
        function EingabeparameterspeichernButtonPushed(app, event)
          
        end

        % Button pushed function: SettingladenButton
        function SettingladenButtonPushed(app, event)
            load(strcat('Settings\',app.bestehendeSettingsDropDown.Value,'.mat'));
          % Allgemeine Einstellungen
            app.HauptgeometrieparameterausmDateivorgebenButton.Value = EingabenAllgemein{1};
            app.EditField.Value = EingabenAllgemein{2};
            app.EditField_2.Value = EingabenAllgemein{3};
            app.zuflligverteilteParametererzeugenButton.Value = EingabenAllgemein{4};
            app.MinimaleAuflsungvorgebenButton.Value = EingabenAllgemein{5};
            app.Auflsungam1RohrelementinUmfangsrichtungEditField.Value = EingabenAllgemein{6};
            app.PunktezahlinUmfangsrichtungButton.Value = EingabenAllgemein{7};
            app.PunktezahlEditField.Value = EingabenAllgemein{8};
            app.VerfeinerungsfaktorderCarinalkurvenauflsungEditField.Value = EingabenAllgemein{9};
          % Kollisionsvermeidung
            app.VerdrehwinkeloptimierenCheckBox.Value = ParamKV{1};
            app.AnzahlderIterationenEditField.Value = ParamKV{2};
            app.GewichtungderDurchmesserEditField.Value = ParamKV{3};
            app.GewichtungderAnschlusspunktAbstndeEditField.Value = ParamKV{4};
            app.gewichtete_Abstandssummen_OB.Value = ParamKV{5};
            app.kleinster_Abstand_OB.Value = ParamKV{6};
            app.KollisionendetektierenCheckBox.Value = ParamKV{7};
            app.PufferAbstandbeiderKollisionsdetektionEditField.Value = ParamKV{8};
          % Geometrieparametererzeugung
            app.AnzahlderGenerationenEditField.Value = GeomParamErzeugung{1};
            app.DurchmesserdeserstenMutterastesEditField.Value = GeomParamErzeugung{2};
            app.LngedeserstenMutterastesEditField.Value = GeomParamErzeugung{3};
            app.VarianzinitialEditField_4.Value = GeomParamErzeugung{4};
            app.ExponentnderungdesStrmungsquerschnittsEditField.Value = GeomParamErzeugung{5};
            app.minimaleLngeLngexDurchmesserEditField.Value = GeomParamErzeugung{6};
            app.maximaleLngeLngexDurchmesserEditField.Value = GeomParamErzeugung{7};
            app.VarianzinitialEditField_3.Value = GeomParamErzeugung{8};
            app.ErwartungswertLngexDurchmesserEditField.Value = GeomParamErzeugung{9};
            app.GrenzverzweigungswinkelEditField.Value = GeomParamErzeugung{10};
            app.VerzweigungswinkelmitExponentEditField.Value = GeomParamErzeugung{11};
            app.AlleBifurkationsbenenliegenineinergemeinsamenEbeneButton.Value = GeomParamErzeugung{12};
            app.JedeBifurkationerhlteineeigeneBifurkationsebeneButton.Value = GeomParamErzeugung{13};
          % Verzweigungsmodell
            app.LngederBronchieneinhaltennurbeivorgegebenenButton.Value = VZWModell{1};
            app.AnteildesgekrmmtenAbschnittsEditField.Value = VZWModell{2};
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_5.Value = VZWModell{3};
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_6.Value = VZWModell{4};
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_2.Value = VZWModell{5};
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_3.Value = VZWModell{6};
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_4.Value = VZWModell{7};
            app.EinflussderTochterdurchmesserEditField.Value = VZWModell{8};
            app.EinflussderVerzweigungswinkelEditField.Value = VZWModell{9};
            app.BreitederCarinalkurveEditField.Value = VZWModell{10};
            app.modifiziertButton.Value = VZWModell{11};
            app.ModulationsparameterEditField_6.Value = VZWModell{12};
            app.SigmoidfunktionnachHeistracherundHofmann1995Button.Value = VZWModell{13};
            app.ModulationsparameterEditField_5.Value = VZWModell{14};
          % Post Processing
            app.ModelloberflcheamallenffnungenschlieenCheckBox.Value = PostProcessing{1};
            app.SockelkonstruierenCheckBox.Value = PostProcessing{2};
            app.Hoehe_Sockel_EF.Value = PostProcessing{3};
            app.Durchmesser_Sockel_EF.Value = PostProcessing{4};
            app.WandstrkekonstruierenCheckBox.Value = PostProcessing{5};
            app.WandstrkeEditField.Value = PostProcessing{6};
            app.WandstrkenachauenauftragenButton.Value = PostProcessing{7};
            app.CarinalverrundunganpassenCheckBox.Value = PostProcessing{8};
            app.WandstrkenachinnenauftragenButton.Value = PostProcessing{9};
            app.FehlertoleranzEditField.Value = PostProcessing{10};
            app.keineButton.Value = PostProcessing{11};
            app.geradeZylinderButton.Value = PostProcessing{12};
            app.Laenge_Endstueck_NEF.Value = PostProcessing{13};
            app.benutzerdefiniertButton.Value = PostProcessing{14};
        end

        % Button pushed function: SettingspeichernButton
        function SettingspeichernButtonPushed(app, event)
            EingabenAllgemein = {app.HauptgeometrieparameterausmDateivorgebenButton.Value, ...
                app.EditField.Value, ...
                app.EditField_2.Value, ...
                app.zuflligverteilteParametererzeugenButton.Value, ...
                app.MinimaleAuflsungvorgebenButton.Value, ...
                app.Auflsungam1RohrelementinUmfangsrichtungEditField.Value, ...
                app.PunktezahlinUmfangsrichtungButton.Value, ...
                app.PunktezahlEditField.Value, ...
                app.VerfeinerungsfaktorderCarinalkurvenauflsungEditField.Value};  
            ParamKV = {app.VerdrehwinkeloptimierenCheckBox.Value,...
                app.AnzahlderIterationenEditField.Value,...
                app.GewichtungderDurchmesserEditField.Value,...
                app.GewichtungderAnschlusspunktAbstndeEditField.Value,...
                app.gewichtete_Abstandssummen_OB.Value,...
                app.kleinster_Abstand_OB.Value,...
                app.KollisionendetektierenCheckBox.Value,...
                app.PufferAbstandbeiderKollisionsdetektionEditField.Value};
            GeomParamErzeugung = {app.AnzahlderGenerationenEditField.Value, ...
                app.DurchmesserdeserstenMutterastesEditField.Value, ...
                app.LngedeserstenMutterastesEditField.Value, ...
                app.VarianzinitialEditField_4.Value, ...
                app.ExponentnderungdesStrmungsquerschnittsEditField.Value, ...
                app.minimaleLngeLngexDurchmesserEditField.Value, ...
                app.maximaleLngeLngexDurchmesserEditField.Value, ...
                app.VarianzinitialEditField_3.Value, ...
                app.ErwartungswertLngexDurchmesserEditField.Value, ...
                app.GrenzverzweigungswinkelEditField.Value, ...
                app.VerzweigungswinkelmitExponentEditField.Value, ...
                app.AlleBifurkationsbenenliegenineinergemeinsamenEbeneButton.Value, ...
                app.JedeBifurkationerhlteineeigeneBifurkationsebeneButton.Value};
            VZWModell = {app.LngederBronchieneinhaltennurbeivorgegebenenButton.Value, ...
                app.AnteildesgekrmmtenAbschnittsEditField.Value, ...
                app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_5.Value, ...
                app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_6.Value, ...
                app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_2.Value, ...
                app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_3.Value, ...
                app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_4.Value, ...
                app.EinflussderTochterdurchmesserEditField.Value, ...
                app.EinflussderVerzweigungswinkelEditField.Value, ...
                app.BreitederCarinalkurveEditField.Value, ...
                app.modifiziertButton.Value, ...
                app.ModulationsparameterEditField_6.Value, ...
                app.SigmoidfunktionnachHeistracherundHofmann1995Button.Value, ...
                app.ModulationsparameterEditField_5.Value};
            PostProcessing = {app.ModelloberflcheamallenffnungenschlieenCheckBox.Value,...
                app.SockelkonstruierenCheckBox.Value,...
                app.Hoehe_Sockel_EF.Value,...
                app.Durchmesser_Sockel_EF.Value,...
                app.WandstrkekonstruierenCheckBox.Value,...
                app.WandstrkeEditField.Value,...
                app.WandstrkenachauenauftragenButton.Value,...
                app.CarinalverrundunganpassenCheckBox.Value,...
                app.WandstrkenachinnenauftragenButton.Value,...
                app.FehlertoleranzEditField.Value,...
                app.keineButton.Value,...
                app.geradeZylinderButton.Value,...
                app.Laenge_Endstueck_NEF.Value,...
                app.benutzerdefiniertButton.Value};
            
            save(strcat('Settings\',app.NamedesSettingsEditField.Value,'.mat'),'EingabenAllgemein','ParamKV','GeomParamErzeugung','VZWModell','PostProcessing');
            load('Settings\Settingnames.mat');
            Settingnames{size(Settingnames,1)+1,1} = app.NamedesSettingsEditField.Value;
            save(strcat('Settings\Settingnames.mat'),'Settingnames');
            app.bestehendeSettingsDropDown.Items = Settingnames;
            APP_Eingabe_gespeichert
        end

        % Button pushed function: SettinglschenButton
        function SettinglschenButtonPushed(app, event)
            load('Settings\Settingnames.mat');
            SettingIndex = find(strcmp(Settingnames,app.bestehendeSettingsDropDown.Value));
            Settingnames(SettingIndex) = [];
            save('Settings\Settingnames.mat','Settingnames');
            delete(strcat('Settings\',app.bestehendeSettingsDropDown.Value,'.mat'));
            app.bestehendeSettingsDropDown.Items = Settingnames;
        end

        % Value changed function: VerdrehwinkeloptimierenCheckBox
        function Kollisionsvermeidung_aktiviert(app, event)
            if app.VerdrehwinkeloptimierenCheckBox.Value == true
                app.GewichtungderDurchmesserEditField.Enable = true;
                app.GewichtungderDurchmesserEditFieldLabel.Enable = true;
                app.negativkleineDurchmesserwerdenstrkergewichtetLabel.Enable = true;
                app.positivgroeDurchmesserwerdenstrkergewichtetLabel.Enable = true;
                app.AnzahlderIterationenEditField.Enable = true;
                app.AnzahlderIterationenEditFieldLabel.Enable = true;
                app.GewichtungderAnschlusspunktAbstndeEditField.Enable = true;
                app.GewichtungderAnschlusspunktAbstndeEditFieldLabel.Enable = true;
                app.negativgroeAbstndewerdenschwchergewichtetLabel.Enable = true;
                app.positivgroeAbstndewerdenstrkergewichtetLabel.Enable = true;
                app.kleinster_Abstand_OB.Enable = true;
                app.gewichtete_Abstandssummen_OB.Enable = true;
                app.Einheit_VWopt_1.Enable = true;
                app.Einheit_VWopt_2.Enable = true;
                app.Einheit_VWopt_3.Enable = true;
            else
                app.GewichtungderDurchmesserEditField.Enable = false;
                app.GewichtungderDurchmesserEditFieldLabel.Enable = false;
                app.GewichtungderDurchmesserEditFieldLabel.Enable = false;
                app.negativkleineDurchmesserwerdenstrkergewichtetLabel.Enable = false;
                app.positivgroeDurchmesserwerdenstrkergewichtetLabel.Enable = false;
                app.AnzahlderIterationenEditField.Enable = false;
                app.AnzahlderIterationenEditFieldLabel.Enable = false;
                app.GewichtungderAnschlusspunktAbstndeEditField.Enable = false;
                app.GewichtungderAnschlusspunktAbstndeEditFieldLabel.Enable = false;
                app.negativgroeAbstndewerdenschwchergewichtetLabel.Enable = false;
                app.positivgroeAbstndewerdenstrkergewichtetLabel.Enable = false;
                app.kleinster_Abstand_OB.Enable = false;
                app.gewichtete_Abstandssummen_OB.Enable = false;
                app.Einheit_VWopt_1.Enable = false;
                app.Einheit_VWopt_2.Enable = false;
                app.Einheit_VWopt_3.Enable = false;
            end
        end

        % Button pushed function: EndstckeditorstartenButton
        function EndstckeditorstartenButtonPushed(app, event)
            APP_Endstueckeditor(app.WandstrkeEditField.Value);
        end

        % Button pushed function: aktualisierenButton
        function aktualisierenButtonPushed(app, event)
            load('Settings\Endstuecknames.mat');
            app.benutzerdefiniertes_Endstueck_DD.Items = Endstuecknames;
        end

        % Value changed function: KollisionendetektierenCheckBox
        function Kollisionsdetektion_aktiviert(app, event)
            if app.KollisionendetektierenCheckBox.Value == true
                app.FaktorzurAuflsungdesZentralachsmodellsEditField.Enable = true;
                app.PufferAbstandbeiderKollisionsdetektionEditField.Enable = true;
                app.FaktorzurAuflsungdesZentralachsmodellsEditFieldLabel.Enable = true;
                app.PufferAbstandbeiderKollisionsdetektionEditFieldLabel.Enable = true;
                app.Einheit_Kdet_1.Enable = true;
                app.Einheit_Kdet_2.Enable = true;
            else
                app.FaktorzurAuflsungdesZentralachsmodellsEditField.Enable = false;
                app.PufferAbstandbeiderKollisionsdetektionEditField.Enable = false;
                app.FaktorzurAuflsungdesZentralachsmodellsEditFieldLabel.Enable = false;
                app.PufferAbstandbeiderKollisionsdetektionEditFieldLabel.Enable = false;
                app.Einheit_Kdet_1.Enable = false;
                app.Einheit_Kdet_2.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HauptmenBronchialbaummodellUIFigure = uifigure('Visible', 'off');
            app.HauptmenBronchialbaummodellUIFigure.Name = 'Hauptmenü Bronchialbaummodell';
            app.TabGroup = uitabgroup(app.HauptmenBronchialbaummodellUIFigure);
            app.AllgemeineModelleigenschaftenTab = uitab(app.TabGroup);
            app.AllgemeineModelleigenschaftenTab.Title = 'Allgemeine Modelleigenschaften';
            app.UrsprungvonGeometrieparameterwertenButtonGroup = uibuttongroup(app.AllgemeineModelleigenschaftenTab);
            app.UrsprungvonGeometrieparameterwertenButtonGroup.Title = 'Ursprung von Geometrieparameterwerten';
            app.HauptgeometrieparameterausmDateivorgebenButton = uiradiobutton(app.UrsprungvonGeometrieparameterwertenButtonGroup);
            app.HauptgeometrieparameterausmDateivorgebenButton.Text = 'Hauptgeometrieparameter aus .m-Datei vorgeben';
            app.zuflligverteilteParametererzeugenButton = uiradiobutton(app.UrsprungvonGeometrieparameterwertenButtonGroup);
            app.zuflligverteilteParametererzeugenButton.Text = 'zufällig verteilte Parameter erzeugen';
            app.zuflligverteilteParametererzeugenButton.Value = true;
            app.NamedesUnterordnersimaktuellenVerzeichnisLabel = uilabel(app.UrsprungvonGeometrieparameterwertenButtonGroup);
            app.NamedesUnterordnersimaktuellenVerzeichnisLabel.Text = 'Name des Unterordners im aktuellen Verzeichnis';
            app.EditField = uieditfield(app.UrsprungvonGeometrieparameterwertenButtonGroup, 'text');
            app.EditField.Value = 'Eingabe';
            app.NamederDateiwechedieEingabevariablenenthltLabel = uilabel(app.UrsprungvonGeometrieparameterwertenButtonGroup);
            app.NamederDateiwechedieEingabevariablenenthltLabel.Text = 'Name der Datei, weche die Eingabevariablen enthält';
            app.EditField_2 = uieditfield(app.UrsprungvonGeometrieparameterwertenButtonGroup, 'text');
            app.Label_3 = uilabel(app.UrsprungvonGeometrieparameterwertenButtonGroup);
            app.Label_3.Text = '\';
            app.Label_4 = uilabel(app.UrsprungvonGeometrieparameterwertenButtonGroup);
            app.Label_4.Text = '\';
            app.matLabel = uilabel(app.UrsprungvonGeometrieparameterwertenButtonGroup);
            app.matLabel.Text = '.mat';
            app.DasEingabefeldGeometrieparametererzeugungbleibtLabel = uilabel(app.UrsprungvonGeometrieparameterwertenButtonGroup);
            app.DasEingabefeldGeometrieparametererzeugungbleibtLabel.Text = 'Das Eingabefeld "Geometrieparametererzeugung" bleibt';
            app.unbercksichtigtLabel = uilabel(app.UrsprungvonGeometrieparameterwertenButtonGroup);
            app.unbercksichtigtLabel.Text = 'unberücksichtigt.';
            app.DieEingabederstatistischenParameterimEingebfeldLabel = uilabel(app.UrsprungvonGeometrieparameterwertenButtonGroup);
            app.DieEingabederstatistischenParameterimEingebfeldLabel.Text = 'Die Eingabe der statistischen Parameter im Eingebfeld';
            app.GeometrieparametererzeugungisterforderlichLabel = uilabel(app.UrsprungvonGeometrieparameterwertenButtonGroup);
            app.GeometrieparametererzeugungisterforderlichLabel.Text = '"Geometrieparametererzeugung" ist erforderlich.';
            app.AuflsungButtonGroup = uibuttongroup(app.AllgemeineModelleigenschaftenTab);
            app.AuflsungButtonGroup.Title = 'Auflösung';
            app.VerfeinerungsfaktorderCarinalkurvenauflsungEditFieldLabel = uilabel(app.AuflsungButtonGroup);
            app.VerfeinerungsfaktorderCarinalkurvenauflsungEditFieldLabel.Text = 'Verfeinerungsfaktor der Carinalkurvenauflösung';
            app.VerfeinerungsfaktorderCarinalkurvenauflsungEditField = uieditfield(app.AuflsungButtonGroup, 'numeric');
            app.MinimaleAuflsungvorgebenButton = uiradiobutton(app.AuflsungButtonGroup);
            app.MinimaleAuflsungvorgebenButton.Text = 'Minimale Auflösung vorgeben';
            app.MinimaleAuflsungvorgebenButton.Value = true;
            app.Auflsungam1RohrelementinUmfangsrichtungEditFieldLabel = uilabel(app.AuflsungButtonGroup);
            app.Auflsungam1RohrelementinUmfangsrichtungEditFieldLabel.Text = 'Auflösung am 1. Rohrelement in Umfangsrichtung';
            app.Auflsungam1RohrelementinUmfangsrichtungEditField = uieditfield(app.AuflsungButtonGroup, 'numeric');
            app.Auflsungam1RohrelementinUmfangsrichtungEditField.Limits = [0 Inf];
            app.Auflsungam1RohrelementinUmfangsrichtungEditField.Value = 500;
            app.AnzahlderGenerationenEditFieldLabel_53 = uilabel(app.AuflsungButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_53.Text = '[µm]';
            app.PunktezahlinUmfangsrichtungButton = uiradiobutton(app.AuflsungButtonGroup);
            app.PunktezahlinUmfangsrichtungButton.Text = 'Punktezahl in Umfangsrichtung';
            app.AnzahlderGenerationenEditFieldLabel_54 = uilabel(app.AuflsungButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_54.Text = '[-]';
            app.PunktezahlEditFieldLabel = uilabel(app.AuflsungButtonGroup);
            app.PunktezahlEditFieldLabel.Text = 'Punktezahl';
            app.PunktezahlEditField = uieditfield(app.AuflsungButtonGroup, 'numeric');
            app.PunktezahlEditField.Limits = [0 Inf];
            app.PunktezahlEditField.Value = 50;
            app.AnzahlderGenerationenEditFieldLabel_55 = uilabel(app.AuflsungButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_55.Text = '[-]';
            app.KollisionenButtonGroup = uibuttongroup(app.AllgemeineModelleigenschaftenTab);
            app.KollisionenButtonGroup.Title = 'Kollisionen';
            app.VerdrehwinkeloptimierenCheckBox = uicheckbox(app.KollisionenButtonGroup);
            app.VerdrehwinkeloptimierenCheckBox.ValueChangedFcn = createCallbackFcn(app, @Kollisionsvermeidung_aktiviert, true);
            app.VerdrehwinkeloptimierenCheckBox.Text = 'Verdrehwinkel optimieren';
            app.VerdrehwinkeloptimierenCheckBox.Value = true;
            app.GewichtungderDurchmesserEditFieldLabel = uilabel(app.KollisionenButtonGroup);
            app.GewichtungderDurchmesserEditFieldLabel.Text = 'Gewichtung der Durchmesser';
            app.GewichtungderDurchmesserEditField = uieditfield(app.KollisionenButtonGroup, 'numeric');
            app.positivgroeDurchmesserwerdenstrkergewichtetLabel = uilabel(app.KollisionenButtonGroup);
            app.positivgroeDurchmesserwerdenstrkergewichtetLabel.Text = 'positiv: große Durchmesser werden stärker gewichtet';
            app.negativkleineDurchmesserwerdenstrkergewichtetLabel = uilabel(app.KollisionenButtonGroup);
            app.negativkleineDurchmesserwerdenstrkergewichtetLabel.Text = 'negativ: kleine Durchmesser werden stärker gewichtet';
            app.AnzahlderIterationenEditFieldLabel = uilabel(app.KollisionenButtonGroup);
            app.AnzahlderIterationenEditFieldLabel.Text = 'Anzahl der Iterationen';
            app.AnzahlderIterationenEditField = uieditfield(app.KollisionenButtonGroup, 'numeric');
            app.AnzahlderIterationenEditField.Value = 10;
            app.positivgroeAbstndewerdenstrkergewichtetLabel = uilabel(app.KollisionenButtonGroup);
            app.positivgroeAbstndewerdenstrkergewichtetLabel.Text = 'positiv: große Abstände werden stärker gewichtet';
            app.negativgroeAbstndewerdenschwchergewichtetLabel = uilabel(app.KollisionenButtonGroup);
            app.negativgroeAbstndewerdenschwchergewichtetLabel.Text = 'negativ: große Abstände werden schwächer gewichtet';
            app.GewichtungderAnschlusspunktAbstndeEditFieldLabel = uilabel(app.KollisionenButtonGroup);
            app.GewichtungderAnschlusspunktAbstndeEditFieldLabel.Text = 'Gewichtung der Anschlusspunkt-Abstände';
            app.GewichtungderAnschlusspunktAbstndeEditField = uieditfield(app.KollisionenButtonGroup, 'numeric');
            app.GewichtungderAnschlusspunktAbstndeEditField.Value = -1;
            app.Einheit_VWopt_2 = uilabel(app.KollisionenButtonGroup);
            app.Einheit_VWopt_2.Text = '[-]';
            app.Einheit_VWopt_3 = uilabel(app.KollisionenButtonGroup);
            app.Einheit_VWopt_3.Text = '[-]';
            app.Einheit_VWopt_1 = uilabel(app.KollisionenButtonGroup);
            app.Einheit_VWopt_1.Text = '[-]';
            app.gewichtete_Abstandssummen_OB = uiradiobutton(app.KollisionenButtonGroup);
            app.gewichtete_Abstandssummen_OB.Text = 'gewichtete Abstandssummen maximieren';
            app.gewichtete_Abstandssummen_OB.Value = true;
            app.kleinster_Abstand_OB = uiradiobutton(app.KollisionenButtonGroup);
            app.kleinster_Abstand_OB.Text = 'kleinsten Abstand maximieren';
            app.KollisionendetektierenCheckBox = uicheckbox(app.KollisionenButtonGroup);
            app.KollisionendetektierenCheckBox.ValueChangedFcn = createCallbackFcn(app, @Kollisionsdetektion_aktiviert, true);
            app.KollisionendetektierenCheckBox.Text = 'Kollisionen detektieren';
            app.KollisionendetektierenCheckBox.Value = true;
            app.Einheit_Kdet_1 = uilabel(app.KollisionenButtonGroup);
            app.Einheit_Kdet_1.Text = '[-]';
            app.FaktorzurAuflsungdesZentralachsmodellsEditFieldLabel = uilabel(app.KollisionenButtonGroup);
            app.FaktorzurAuflsungdesZentralachsmodellsEditFieldLabel.Text = 'Faktor zur Auflösung des Zentralachsmodells';
            app.FaktorzurAuflsungdesZentralachsmodellsEditField = uieditfield(app.KollisionenButtonGroup, 'numeric');
            app.FaktorzurAuflsungdesZentralachsmodellsEditField.Limits = [1 Inf];
            app.FaktorzurAuflsungdesZentralachsmodellsEditField.Value = 3;
            app.Einheit_Kdet_2 = uilabel(app.KollisionenButtonGroup);
            app.Einheit_Kdet_2.Text = '[mm]';
            app.PufferAbstandbeiderKollisionsdetektionEditFieldLabel = uilabel(app.KollisionenButtonGroup);
            app.PufferAbstandbeiderKollisionsdetektionEditFieldLabel.Text = 'Puffer-Abstand bei der Kollisionsdetektion';
            app.PufferAbstandbeiderKollisionsdetektionEditField = uieditfield(app.KollisionenButtonGroup, 'numeric');
            app.PufferAbstandbeiderKollisionsdetektionEditField.Limits = [0 Inf];
            app.GeometrieparametererzeugungTab = uitab(app.TabGroup);
            app.GeometrieparametererzeugungTab.Title = 'Geometrieparametererzeugung';
            app.allgemeineEigenschaftendesVerzweigungsmodellsPanel = uipanel(app.GeometrieparametererzeugungTab);
            app.allgemeineEigenschaftendesVerzweigungsmodellsPanel.Title = 'allgemeine Eigenschaften des Verzweigungsmodells';
            app.AnzahlderGenerationenEditFieldLabel_7 = uilabel(app.allgemeineEigenschaftendesVerzweigungsmodellsPanel);
            app.AnzahlderGenerationenEditFieldLabel_7.Text = '[mm]';
            app.AnzahlderGenerationenEditFieldLabel_8 = uilabel(app.allgemeineEigenschaftendesVerzweigungsmodellsPanel);
            app.AnzahlderGenerationenEditFieldLabel_8.Text = '[-]';
            app.AnzahlderGenerationenEditFieldLabel_56 = uilabel(app.allgemeineEigenschaftendesVerzweigungsmodellsPanel);
            app.AnzahlderGenerationenEditFieldLabel_56.Text = 'Anzahl der Generationen';
            app.AnzahlderGenerationenEditField = uieditfield(app.allgemeineEigenschaftendesVerzweigungsmodellsPanel, 'numeric');
            app.AnzahlderGenerationenEditField.Value = 3;
            app.DurchmesserdeserstenMutterastesEditFieldLabel = uilabel(app.allgemeineEigenschaftendesVerzweigungsmodellsPanel);
            app.DurchmesserdeserstenMutterastesEditFieldLabel.Text = 'Durchmesser des ersten Mutterastes';
            app.DurchmesserdeserstenMutterastesEditField = uieditfield(app.allgemeineEigenschaftendesVerzweigungsmodellsPanel, 'numeric');
            app.DurchmesserdeserstenMutterastesEditField.Value = 4;
            app.AnzahlderGenerationenEditFieldLabel_30 = uilabel(app.allgemeineEigenschaftendesVerzweigungsmodellsPanel);
            app.AnzahlderGenerationenEditFieldLabel_30.Text = '[mm]';
            app.LngedeserstenMutterastesEditFieldLabel = uilabel(app.allgemeineEigenschaftendesVerzweigungsmodellsPanel);
            app.LngedeserstenMutterastesEditFieldLabel.Text = 'Länge des ersten Mutterastes';
            app.LngedeserstenMutterastesEditField = uieditfield(app.allgemeineEigenschaftendesVerzweigungsmodellsPanel, 'numeric');
            app.LngedeserstenMutterastesEditField.Value = 4;
            app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup = uibuttongroup(app.GeometrieparametererzeugungTab);
            app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup.Title = 'Abhängigkeit zwischen Durchmesser und Rohrelementlänge';
            app.normalverteiltpositivkorreliertButton = uiradiobutton(app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup);
            app.normalverteiltpositivkorreliertButton.Visible = 'off';
            app.normalverteiltpositivkorreliertButton.Text = 'normalverteilt, positiv korreliert';
            app.normalverteiltpositivkorreliertButton.Value = true;
            app.AnzahlderGenerationenEditFieldLabel_15 = uilabel(app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_15.Text = '[-]';
            app.AnzahlderGenerationenEditFieldLabel_16 = uilabel(app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_16.Text = '[-]';
            app.VarianzinitialEditField_3Label = uilabel(app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup);
            app.VarianzinitialEditField_3Label.Text = 'Varianz (initial)';
            app.VarianzinitialEditField_3 = uieditfield(app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup, 'numeric');
            app.VarianzinitialEditField_3.Limits = [0 Inf];
            app.VarianzinitialEditField_3.Value = 0.01;
            app.ErwartungswertLngexDurchmesserEditFieldLabel = uilabel(app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup);
            app.ErwartungswertLngexDurchmesserEditFieldLabel.Text = 'Erwartungswert (Länge = x*Durchmesser)';
            app.ErwartungswertLngexDurchmesserEditField = uieditfield(app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup, 'numeric');
            app.ErwartungswertLngexDurchmesserEditField.Limits = [0 Inf];
            app.ErwartungswertLngexDurchmesserEditField.Value = 0.01;
            app.AnzahlderGenerationenEditFieldLabel_39 = uilabel(app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_39.Text = '[-]';
            app.AnzahlderGenerationenEditFieldLabel_40 = uilabel(app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_40.Text = '[-]';
            app.minimaleLngeLngexDurchmesserEditFieldLabel = uilabel(app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup);
            app.minimaleLngeLngexDurchmesserEditFieldLabel.Text = 'minimale Länge (Länge = x*Durchmesser)';
            app.minimaleLngeLngexDurchmesserEditField = uieditfield(app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup, 'numeric');
            app.minimaleLngeLngexDurchmesserEditField.Limits = [0 Inf];
            app.minimaleLngeLngexDurchmesserEditField.Value = 0.01;
            app.maximaleLngeLngexDurchmesserEditFieldLabel = uilabel(app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup);
            app.maximaleLngeLngexDurchmesserEditFieldLabel.Text = 'maximale Länge (Länge = x*Durchmesser)';
            app.maximaleLngeLngexDurchmesserEditField = uieditfield(app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup, 'numeric');
            app.maximaleLngeLngexDurchmesserEditField.Limits = [0 Inf];
            app.maximaleLngeLngexDurchmesserEditField.Value = 0.01;
            app.normalverteiltLabel = uilabel(app.AbhngigkeitzwischenDurchmesserundRohrelementlngeButtonGroup);
            app.normalverteiltLabel.Text = 'normalverteilt:';
            app.NenndurchmesserderBronchienButtonGroup = uibuttongroup(app.GeometrieparametererzeugungTab);
            app.NenndurchmesserderBronchienButtonGroup.Title = 'Nenndurchmesser der Bronchien';
            app.normalverteiltButton = uiradiobutton(app.NenndurchmesserderBronchienButtonGroup);
            app.normalverteiltButton.Visible = 'off';
            app.normalverteiltButton.Text = 'normalverteilt';
            app.normalverteiltButton.Value = true;
            app.AnzahlderGenerationenEditFieldLabel_19 = uilabel(app.NenndurchmesserderBronchienButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_19.Text = '[-]';
            app.ExponentnderungdesStrmungsquerschnittsEditFieldLabel = uilabel(app.NenndurchmesserderBronchienButtonGroup);
            app.ExponentnderungdesStrmungsquerschnittsEditFieldLabel.Text = 'Exponent: Änderung des Strömungsquerschnitts';
            app.ExponentnderungdesStrmungsquerschnittsEditField = uieditfield(app.NenndurchmesserderBronchienButtonGroup, 'numeric');
            app.ExponentnderungdesStrmungsquerschnittsEditField.Limits = [0 Inf];
            app.ExponentnderungdesStrmungsquerschnittsEditField.Value = 2.885;
            app.AnzahlderGenerationenEditFieldLabel_21 = uilabel(app.NenndurchmesserderBronchienButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_21.Text = '[-]';
            app.VarianzinitialEditField_4Label = uilabel(app.NenndurchmesserderBronchienButtonGroup);
            app.VarianzinitialEditField_4Label.Text = 'Varianz (initial)';
            app.VarianzinitialEditField_4 = uieditfield(app.NenndurchmesserderBronchienButtonGroup, 'numeric');
            app.VarianzinitialEditField_4.Limits = [0 Inf];
            app.VarianzinitialEditField_4.Value = 0.5;
            app.normalverteiltLabel_2 = uilabel(app.NenndurchmesserderBronchienButtonGroup);
            app.normalverteiltLabel_2.Text = 'normalverteilt:';
            app.VerzweigungswinkelButtonGroup = uibuttongroup(app.GeometrieparametererzeugungTab);
            app.VerzweigungswinkelButtonGroup.Title = 'Verzweigungswinkel';
            app.AnzahlderGenerationenEditFieldLabel_4 = uilabel(app.VerzweigungswinkelButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_4.Text = '[°]';
            app.GrenzverzweigungswinkelEditFieldLabel = uilabel(app.VerzweigungswinkelButtonGroup);
            app.GrenzverzweigungswinkelEditFieldLabel.Text = 'Grenzverzweigungswinkel';
            app.GrenzverzweigungswinkelEditField = uieditfield(app.VerzweigungswinkelButtonGroup, 'numeric');
            app.GrenzverzweigungswinkelEditField.Limits = [-90 90];
            app.GrenzverzweigungswinkelEditField.Value = 90;
            app.AnzahlderGenerationenEditFieldLabel_31 = uilabel(app.VerzweigungswinkelButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_31.Visible = 'off';
            app.AnzahlderGenerationenEditFieldLabel_31.Text = '[°]';
            app.exponentiellmitExponentxButton = uiradiobutton(app.VerzweigungswinkelButtonGroup);
            app.exponentiellmitExponentxButton.Visible = 'off';
            app.exponentiellmitExponentxButton.Text = '';
            app.exponentiellmitExponentxButton.Value = true;
            app.VerzweigungswinkelmitExponentEditFieldLabel = uilabel(app.VerzweigungswinkelButtonGroup);
            app.VerzweigungswinkelmitExponentEditFieldLabel.Text = 'Verzweigungswinkel mit Exponent';
            app.VerzweigungswinkelmitExponentEditField = uieditfield(app.VerzweigungswinkelButtonGroup, 'numeric');
            app.VerzweigungswinkelmitExponentEditField.Limits = [-90 90];
            app.VerzweigungswinkelmitExponentEditField.Value = 3.5;
            app.exponentiellerZusammenhangzwischenDurchmesserundLabel = uilabel(app.VerzweigungswinkelButtonGroup);
            app.exponentiellerZusammenhangzwischenDurchmesserundLabel.Text = 'exponentieller Zusammenhang zwischen Durchmesser und';
            app.AnzahlderGenerationenEditFieldLabel_57 = uilabel(app.VerzweigungswinkelButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_57.Text = '[-]';
            app.LagederBifurkationsebenenButtonGroup = uibuttongroup(app.GeometrieparametererzeugungTab);
            app.LagederBifurkationsebenenButtonGroup.Title = 'Lage der Bifurkationsebenen';
            app.AlleBifurkationsbenenliegenineinergemeinsamenEbeneButton = uiradiobutton(app.LagederBifurkationsebenenButtonGroup);
            app.AlleBifurkationsbenenliegenineinergemeinsamenEbeneButton.Text = 'Alle Bifurkationsbenen liegen in einer gemeinsamen Ebene.';
            app.JedeBifurkationerhlteineeigeneBifurkationsebeneButton = uiradiobutton(app.LagederBifurkationsebenenButtonGroup);
            app.JedeBifurkationerhlteineeigeneBifurkationsebeneButton.Text = 'Jede Bifurkation erhält eine eigene Bifurkationsebene.';
            app.JedeBifurkationerhlteineeigeneBifurkationsebeneButton.Value = true;
            app.BifurkationsmodellTab = uitab(app.TabGroup);
            app.BifurkationsmodellTab.Title = 'Bifurkationsmodell';
            app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup = uibuttongroup(app.BifurkationsmodellTab);
            app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup.Title = 'Verhältnis Krümmungsradius zum Rohrdurchmesser';
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_2 = uieditfield(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup, 'numeric');
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_2.Limits = [0 Inf];
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_3 = uieditfield(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup, 'numeric');
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_3.Limits = [0 Inf];
            app.DLabel = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.DLabel.Text = '  D  + ';
            app.ParameterderPolynomfunktionLabel = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.ParameterderPolynomfunktionLabel.Text = 'Parameter der Polynomfunktion';
            app.MLabel = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.MLabel.Text = 'M      = ';
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_4 = uieditfield(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup, 'numeric');
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_4.Limits = [0 Inf];
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_4.Value = 1;
            app.Label = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.Label.Text = '+ ';
            app.DLabel_3 = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.DLabel_3.Text = 'D';
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_5 = uieditfield(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup, 'numeric');
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_5.Limits = [0 Inf];
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_5.Value = 1;
            app.DLabel_2 = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.DLabel_2.Text = ' D';
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_6 = uieditfield(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup, 'numeric');
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_6.Limits = [0 Inf];
            app.VerhltnisKrmmungsradiusRohrdurchmesserEditField_6.Value = 1.75;
            app.Label_2 = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.Label_2.Text = '+ ';
            app.VerrundungsparametereinhaltenButton = uiradiobutton(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.VerrundungsparametereinhaltenButton.Text = 'Verrundungsparameter einhalten';
            app.VerrundungsparametereinhaltenButton.Value = true;
            app.LngederBronchieneinhaltennurbeivorgegebenenButton = uiradiobutton(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.LngederBronchieneinhaltennurbeivorgegebenenButton.Text = 'Länge der Bronchien einhalten (nur bei vorgegebenen';
            app.AnzahlderGenerationenEditFieldLabel_50 = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_50.Text = '[-]';
            app.AnteildesgekrmmtenAbschnittsEditFieldLabel = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.AnteildesgekrmmtenAbschnittsEditFieldLabel.Text = 'Anteil des gekrümmten Abschnitts';
            app.AnteildesgekrmmtenAbschnittsEditField = uieditfield(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup, 'numeric');
            app.AnteildesgekrmmtenAbschnittsEditField.Limits = [0 1];
            app.AnteildesgekrmmtenAbschnittsEditField.Value = 0.9;
            app.HauptgeometrieparameternvglAllgemeineModelleigenschaftenLabel = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.HauptgeometrieparameternvglAllgemeineModelleigenschaftenLabel.Text = 'Hauptgeometrieparametern, vgl. Allgemeine Modelleigenschaften)';
            app.KxLabel = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.KxLabel.Text = 'K,x';
            app.iLabel = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.iLabel.Text = 'i';
            app.Label_5 = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.Label_5.Text = '.';
            app.iLabel_2 = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.iLabel_2.Text = 'i';
            app.Label_6 = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.Label_6.Text = '.';
            app.Label_7 = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.Label_7.Text = '0';
            app.Label_8 = uilabel(app.VerhltnisKrmmungsradiuszumRohrdurchmesserButtonGroup);
            app.Label_8.Text = '.';
            app.CarinalverrundungButtonGroup = uibuttongroup(app.BifurkationsmodellTab);
            app.CarinalverrundungButtonGroup.Title = 'Carinalverrundung';
            app.AnzahlderGenerationenEditFieldLabel_29 = uilabel(app.CarinalverrundungButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_29.Text = '[-]';
            app.EinflussderTochterdurchmesserEditFieldLabel = uilabel(app.CarinalverrundungButtonGroup);
            app.EinflussderTochterdurchmesserEditFieldLabel.Text = 'Einfluss der Tochterdurchmesser';
            app.EinflussderTochterdurchmesserEditField = uieditfield(app.CarinalverrundungButtonGroup, 'numeric');
            app.EinflussderTochterdurchmesserEditField.Limits = [0 Inf];
            app.EinflussderTochterdurchmesserEditField.Value = 0.1;
            app.AnzahlderGenerationenEditFieldLabel_32 = uilabel(app.CarinalverrundungButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_32.Text = '[-]';
            app.EinflussderVerzweigungswinkelEditFieldLabel = uilabel(app.CarinalverrundungButtonGroup);
            app.EinflussderVerzweigungswinkelEditFieldLabel.Text = 'Einfluss der Verzweigungswinkel';
            app.EinflussderVerzweigungswinkelEditField = uieditfield(app.CarinalverrundungButtonGroup, 'numeric');
            app.EinflussderVerzweigungswinkelEditField.Limits = [0 Inf];
            app.AnzahlderGenerationenEditFieldLabel_38 = uilabel(app.CarinalverrundungButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_38.Text = '[-]';
            app.BreitederCarinalkurveEditFieldLabel = uilabel(app.CarinalverrundungButtonGroup);
            app.BreitederCarinalkurveEditFieldLabel.Text = 'Breite der Carinalkurve';
            app.BreitederCarinalkurveEditField = uieditfield(app.CarinalverrundungButtonGroup, 'numeric');
            app.SigmoidfunktionButtonGroup_2 = uibuttongroup(app.BifurkationsmodellTab);
            app.SigmoidfunktionButtonGroup_2.Title = 'Sigmoidfunktion';
            app.modifiziertButton = uiradiobutton(app.SigmoidfunktionButtonGroup_2);
            app.modifiziertButton.Text = 'modifiziert';
            app.modifiziertButton.Value = true;
            app.SigmoidfunktionnachHeistracherundHofmann1995Button = uiradiobutton(app.SigmoidfunktionButtonGroup_2);
            app.SigmoidfunktionnachHeistracherundHofmann1995Button.Text = 'Sigmoidfunktion nach Heistracher und Hofmann (1995)';
            app.AnzahlderGenerationenEditFieldLabel_36 = uilabel(app.SigmoidfunktionButtonGroup_2);
            app.AnzahlderGenerationenEditFieldLabel_36.Text = '[-]';
            app.ModulationsparameterEditField_5Label = uilabel(app.SigmoidfunktionButtonGroup_2);
            app.ModulationsparameterEditField_5Label.Text = 'Modulationsparameter';
            app.ModulationsparameterEditField_5 = uieditfield(app.SigmoidfunktionButtonGroup_2, 'numeric');
            app.ModulationsparameterEditField_5.Limits = [0 1];
            app.ModulationsparameterEditField_5.Value = 0.5;
            app.AnzahlderGenerationenEditFieldLabel_37 = uilabel(app.SigmoidfunktionButtonGroup_2);
            app.AnzahlderGenerationenEditFieldLabel_37.Text = '[-]';
            app.ModulationsparameterEditField_6Label = uilabel(app.SigmoidfunktionButtonGroup_2);
            app.ModulationsparameterEditField_6Label.Text = 'Modulationsparameter';
            app.ModulationsparameterEditField_6 = uieditfield(app.SigmoidfunktionButtonGroup_2, 'numeric');
            app.ModulationsparameterEditField_6.Limits = [1 Inf];
            app.ModulationsparameterEditField_6.Value = 1.3;
            app.PostProcessingTab = uitab(app.TabGroup);
            app.PostProcessingTab.Title = 'Post-Processing';
            app.EndstckeButtonGroup = uibuttongroup(app.PostProcessingTab);
            app.EndstckeButtonGroup.Title = 'Endstücke';
            app.AnzahlderGenerationenEditFieldLabel_41 = uilabel(app.EndstckeButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_41.Text = '[mm]';
            app.LngeEditFieldLabel = uilabel(app.EndstckeButtonGroup);
            app.LngeEditFieldLabel.Text = 'Länge';
            app.Laenge_Endstueck_NEF = uieditfield(app.EndstckeButtonGroup, 'numeric');
            app.Laenge_Endstueck_NEF.Limits = [0 Inf];
            app.geradeZylinderButton = uiradiobutton(app.EndstckeButtonGroup);
            app.geradeZylinderButton.Text = 'gerade Zylinder';
            app.keineButton = uiradiobutton(app.EndstckeButtonGroup);
            app.keineButton.Text = 'keine';
            app.keineButton.Value = true;
            app.benutzerdefiniertButton = uiradiobutton(app.EndstckeButtonGroup);
            app.benutzerdefiniertButton.Text = 'benutzerdefiniert';
            app.EndstckeditorstartenButton = uibutton(app.EndstckeButtonGroup, 'push');
            app.EndstckeditorstartenButton.ButtonPushedFcn = createCallbackFcn(app, @EndstckeditorstartenButtonPushed, true);
            app.EndstckeditorstartenButton.Text = 'Endstückeditor starten';
            app.benutzerdefiniertes_Endstueck_DD = uidropdown(app.EndstckeButtonGroup);
            app.aktualisierenButton = uibutton(app.EndstckeButtonGroup, 'push');
            app.aktualisierenButton.ButtonPushedFcn = createCallbackFcn(app, @aktualisierenButtonPushed, true);
            app.aktualisierenButton.Text = 'aktualisieren';
            app.SockelButtonGroup = uibuttongroup(app.PostProcessingTab);
            app.SockelButtonGroup.Title = 'Sockel';
            app.HheEditFieldLabel = uilabel(app.SockelButtonGroup);
            app.HheEditFieldLabel.Text = 'Höhe';
            app.Hoehe_Sockel_EF = uieditfield(app.SockelButtonGroup, 'numeric');
            app.Hoehe_Sockel_EF.Limits = [0 Inf];
            app.Hoehe_Sockel_EF.Value = 25;
            app.AnzahlderGenerationenEditFieldLabel_42 = uilabel(app.SockelButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_42.Text = '[mm]';
            app.AnzahlderGenerationenEditFieldLabel_43 = uilabel(app.SockelButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_43.Text = '[mm]';
            app.DurchmesserEditFieldLabel = uilabel(app.SockelButtonGroup);
            app.DurchmesserEditFieldLabel.Text = 'Durchmesser';
            app.Durchmesser_Sockel_EF = uieditfield(app.SockelButtonGroup, 'numeric');
            app.Durchmesser_Sockel_EF.Limits = [0 Inf];
            app.Durchmesser_Sockel_EF.Value = 25;
            app.SockelkonstruierenCheckBox = uicheckbox(app.SockelButtonGroup);
            app.SockelkonstruierenCheckBox.Text = 'Sockel konstruieren';
            app.WandstrkeButtonGroup = uibuttongroup(app.PostProcessingTab);
            app.WandstrkeButtonGroup.Title = 'Wandstärke';
            app.WandstrkeEditFieldLabel = uilabel(app.WandstrkeButtonGroup);
            app.WandstrkeEditFieldLabel.Text = 'Wandstärke';
            app.WandstrkeEditField = uieditfield(app.WandstrkeButtonGroup, 'numeric');
            app.WandstrkeEditField.Limits = [0 Inf];
            app.WandstrkeEditField.Value = 1;
            app.WandstrkenachinnenauftragenButton = uiradiobutton(app.WandstrkeButtonGroup);
            app.WandstrkenachinnenauftragenButton.Text = 'Wandstärke nach innen auftragen';
            app.AnzahlderGenerationenEditFieldLabel_51 = uilabel(app.WandstrkeButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_51.Text = '[mm]';
            app.WandstrkenachauenauftragenButton = uiradiobutton(app.WandstrkeButtonGroup);
            app.WandstrkenachauenauftragenButton.Text = 'Wandstärke nach außen auftragen';
            app.WandstrkenachauenauftragenButton.Value = true;
            app.WandstrkekonstruierenCheckBox = uicheckbox(app.WandstrkeButtonGroup);
            app.WandstrkekonstruierenCheckBox.Text = 'Wandstärke konstruieren';
            app.CarinalverrundunganpassenCheckBox = uicheckbox(app.WandstrkeButtonGroup);
            app.CarinalverrundunganpassenCheckBox.Text = 'Carinalverrundung anpassen';
            app.CarinalverrundunganpassenCheckBox.Value = true;
            app.FehlertoleranzEditFieldLabel = uilabel(app.WandstrkeButtonGroup);
            app.FehlertoleranzEditFieldLabel.Text = 'Fehlertoleranz';
            app.FehlertoleranzEditField = uieditfield(app.WandstrkeButtonGroup, 'numeric');
            app.FehlertoleranzEditField.Limits = [0 Inf];
            app.FehlertoleranzEditField.Value = 1e-06;
            app.AnzahlderGenerationenEditFieldLabel_52 = uilabel(app.WandstrkeButtonGroup);
            app.AnzahlderGenerationenEditFieldLabel_52.Text = '[mm]';
            app.ModelloberflcheamallenffnungenschlieenCheckBox = uicheckbox(app.PostProcessingTab);
            app.ModelloberflcheamallenffnungenschlieenCheckBox.Text = 'Modelloberfläche am allen Öffnungen schließen';
            app.BerechnungstartenButton = uibutton(app.HauptmenBronchialbaummodellUIFigure, 'push');
            app.BerechnungstartenButton.ButtonPushedFcn = createCallbackFcn(app, @FNC_Main, true);
            app.BerechnungstartenButton.Text = 'Berechnung starten';
            app.SpeichernundLadenvonEinstellungenPanel = uipanel(app.HauptmenBronchialbaummodellUIFigure);
            app.SpeichernundLadenvonEinstellungenPanel.Title = 'Speichern und Laden von Einstellungen';
            app.SettingladenButton = uibutton(app.SpeichernundLadenvonEinstellungenPanel, 'push');
            app.SettingladenButton.ButtonPushedFcn = createCallbackFcn(app, @SettingladenButtonPushed, true);
            app.SettingladenButton.Text = 'Setting laden';
            app.SettingspeichernButton = uibutton(app.SpeichernundLadenvonEinstellungenPanel, 'push');
            app.SettingspeichernButton.ButtonPushedFcn = createCallbackFcn(app, @SettingspeichernButtonPushed, true);
            app.SettingspeichernButton.Text = 'Setting speichern';
            app.SettinglschenButton = uibutton(app.SpeichernundLadenvonEinstellungenPanel, 'push');
            app.SettinglschenButton.ButtonPushedFcn = createCallbackFcn(app, @SettinglschenButtonPushed, true);
            app.SettinglschenButton.Text = 'Setting löschen';
            app.bestehendeSettingsDropDownLabel = uilabel(app.SpeichernundLadenvonEinstellungenPanel);
            app.bestehendeSettingsDropDownLabel.Text = 'bestehende Settings';
            app.bestehendeSettingsDropDown = uidropdown(app.SpeichernundLadenvonEinstellungenPanel);
            app.NamedesSettingsEditFieldLabel = uilabel(app.SpeichernundLadenvonEinstellungenPanel);
            app.NamedesSettingsEditFieldLabel.Text = 'Name des Settings';
            app.NamedesSettingsEditField = uieditfield(app.SpeichernundLadenvonEinstellungenPanel, 'text');
            % Show the figure after all components are created
            app.HauptmenBronchialbaummodellUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_Hauptmenue

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HauptmenBronchialbaummodellUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HauptmenBronchialbaummodellUIFigure)
        end
    end
end